classdef Ballistic_Capture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EarthMoonManifoldTubesButton  matlab.ui.control.Button
        EnergyEditFieldLabel          matlab.ui.control.Label
        EnergyEditField               matlab.ui.control.NumericEditField
        EnergyLevels                  matlab.ui.control.UIAxes
        ViewEnergyLevelsButton        matlab.ui.control.Button
        SystemDropDownLabel           matlab.ui.control.Label
        SystemDropDown                matlab.ui.control.DropDown
        ManifoldTubes_ES              matlab.ui.control.UIAxes
        ManifoldTubes_SE              matlab.ui.control.UIAxes
        SunEarthManifoldTubesButton   matlab.ui.control.Button
        FindBallisticCaptureButton    matlab.ui.control.Button
        PoincSec                      matlab.ui.control.UIAxes
        PhaseAngleListBoxLabel        matlab.ui.control.Label
        PhaseAngleListBox             matlab.ui.control.ListBox
        SelectTrajectoryButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        mu_ES % Earth-Moon Orbital Parameter
        mu_SE % Sun-Earth Orbital Parameter
        E %Desired Energy
        S_Mani_IC_Pos_First_Poinc %Stable Positive Manifold ICs
        S_Mani_IC_Neg_First_Poinc %Stable Negative Manifold ICs
        U_Mani_IC_Pos_First_Poinc %Unstable Positive Manifold ICs
        U_Mani_IC_Neg_First_Poinc %Unstable Negative Manifold ICs
        PoincSect %Current Poincare Section
        L_ES % Earth-Moon Non-dimensionalization paramter
        V_ES % Earth-Moon Non-dimensionalization paramter
        T_ES % Earth-Moon Non-dimensionalization paramter
        L_SE % Sun-Earth Non-dimensionalization paramter
        V_SE % Sun-Earth Non-dimensionalization paramter
        T_SE % Sun-Earth Non-dimensionalization paramter
        mu % Specific Orbital Parameter
        xL2SPTube % Stable Positive Tube IC
        xL2SPTubeSolution % Stable Positive Tube time history
        posx % x-position
        posy % y-position
        dynamics % EOM
        Sect_Int % Intersection Section
        CompSect % Compared Sections
        Sect_x_avg % Section x-average
        FullSections % Full Section Storage
        TranSections % Transformed Sections
    end
    
    methods (Access = private)
        
        function plotFamily(app,globalVar)
            % Inputs
            % ------
            % 1) globalVar   - Global data.
            %
            % Outputs
            % --------
            % Plot of Lyapunov Family to dashboard
            %
            
            % Extracting Initial Conditions for Plotting the Orbit
            [familyPar] = lyapunovFamily(app,globalVar);
            
            %Assign Family IC
            app.FamilyIC = familyPar.IC;
            if ~ishold(app.LyapunovFamily) %If the plot is not held it will be a new run, if not we are wanting to plot over what we already have there
                hold(app.LyapunovFamily);
            end
            
            % Plotting the Family
            for i = 1:length(familyPar.jacobianConst)
                [~,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,familyPar.IC(i,:),[0 familyPar.period(i)],'forward');
                plot3(app.LyapunovFamily,x(:,1),x(:,2),zeros(length(x),1));
            end
            
            scatter3(app.LyapunovFamily,globalVar.lagPts.pos(globalVar.userInput.lagrangePt,1),globalVar.lagPts.pos(globalVar.userInput.lagrangePt,2),0,'+','filled');
            
            scatter3(app.LyapunovFamily,1-globalVar.userInput.mu,0,0,'o','filled');
            
            %Change the view
            view(app.LyapunovFamily,2);
            xlabel(app.LyapunovFamily,'X (Non-Dim)')
            ylabel(app.LyapunovFamily,'Y (Non-Dim)')
            title(app.LyapunovFamily,'Generated Lyapunov Family')
            
            %Color axis if plotting not on dashboard
            %caxis([familyPar.jacobianConst(end), familyPar.jacobianConst(1)]);
            %colorbar
        end
        
        function [familyPar] = lyapunovFamily(app,globalVar)
            % Inputs
            % ------
            % 1) globalVar   - Global data.
            %
            % Outputs
            % --------
            % 1) familyPar - A structure containing
            %                 - time      - full time period for lyapunov orbit.
            %                 - IC        - Initial Conditions for Lyapunov Orbit.
            %                 - jacobianConst    - jacobianConst Value for Lyapunov Orbit.
            %                 - Monodromy - Monodromy matrix of Lyapunov Orbit.
            %                 - eigens    - Eigen Values and Eigen Vectors for Lyapunov Orbit.
            %                                     - Stable,Unstable and Center Eigen Values
            %                                     - Stable,Unstable and Center Eigen Vectors
            %
            
            
            % Extract the parameters
            orbitCount = globalVar.userInput.orbitCount; %Number of orbit desired to be plotted
            mud = globalVar.userInput.mu; %Specific mu, done this way to clean up code for automation
            funVarEq = globalVar.functions.varEq_stmDot; %State Transition Matrix Variational Equations
            
            % Obtain Initial Guess
            [guess] = initGuess(app,globalVar);
            
            % Differential Correction and Natural Parameter Continuation
            for i = 1:orbitCount
                if i > 2
                    delta = (x(i-1,:) - x(i-2,:));  %Numerical Continuation
                    xGuess = x(i-1,:) + delta;
                    [~,~,~,isMaxIterReached] = diffCorrec(app,xGuess,globalVar); %Differential Corrector Function
                    %If we do not find a good solution quickly, reduce the delta step
                    while isMaxIterReached
                        delta = delta/2;
                        xGuess = x(i-1,:) + delta;
                        [~,~,~,isMaxIterReached] = diffCorrec(app,xGuess,globalVar); %Differential Corrector Function
                    end
                else
                    xGuess = guess(i,:); %Store for future analysis
                end
                
                [t(i,1),x(i,:),~] = diffCorrec(app,xGuess,globalVar); %Differential Corrector Function
                jacobianConst(i,1) = jacobiValue3D(app,x(i,:),mud); %Calculate Energy Value
                [~,monodromy(:,:,i),~,~] = stm_X(app,globalVar,x(i,:),funVarEq,t(i,:)); %Find STate Transition Matrix
                [eigens(i).val.stable,eigens(i).val.unstable,eigens(i).val.center,eigens(i).val.p,eigens(i).vec.stable,...
                    eigens(i).vec.unstable,eigens(i).vec.center,eigens(i).vec.p] = calcEigen(app,monodromy(:,:,i),1) ; %Calculate Eigenvalues of Monodromy Matrix
                stabilityIdx(i,1) = calcStabilityIdx(app,eigens(i)); %Compute Stability index of perioidc orbit
                
            end
            
            %Construct Output
            familyPar.period = t; %Full Orbit Time
            familyPar.IC = x; %Initial Conditions
            familyPar.jacobianConst = jacobianConst; %Energy Value
            familyPar.monodromy = monodromy; %Monodromy Matrix
            familyPar.eigens = eigens; %Eigenvalues
            familyPar.stabilityIdx = stabilityIdx; %Stability Index
        end
        
        function [tCorrec,xCorrec,DF,isMaxIterReached] = diffCorrec(app,xGuess,globalVar)
            %Inputs
            %------
            %1) xGuess - Initial Guess
            %2) globalVar - Global Variable
            %
            %Outputs
            %--------
            %1) tCorrec - Period Time
            %2) xCorrec - Corrected Initial Condition to obtain the periodic orbit.
            %3) DF - Final Differential Correction Value
            %4) isMaxIterReached
            
            %Pull Data
            orbit = globalVar.userInput.orbit;
            f1 = globalVar.functions.systemDynamics;
            f2 = globalVar.functions.varEq_stmDot;
            tol = globalVar.userInput.tolerance^2;
            
            %Set a large timespan for first crossing
            tspan = [0 10];
            
            isMaxIterReached = 0;
            maxIter = 10;
            iter = 1;
            
            %Determine if Halo orbit or not, feature not included in this version
            if xGuess(3) == 0 && strcmp(orbit,'halo')
                orbit = 'halo';
            elseif xGuess(6) == 0 && strcmp(orbit,'axial')
                orbit = 'lyapunov';
            end
            %Set some initial conditions
            del_xDotf = 1;
            del_zDotf = 1;
            del_zf = 1;
            switch orbit
                %Set what variables are to be modified, taken from Dynamical Systems book by Ross et. al.
                case 'lyapunov'
                    xFree = xGuess(5);
                    Fx = [del_xDotf;del_zDotf]; % Constraint vector
                case 'halo'
                    xFree = [xGuess(1);xGuess(5)];
                    Fx = [del_xDotf;del_zDotf]; % Constraint vector
                case 'halo2'
                    xFree = [xGuess(3);xGuess(5)];
                    Fx = [del_xDotf;del_zDotf]; % Constraint vector
                case 'axial'
                    xFree = [xGuess(1); xGuess(5)];
                    Fx = [del_zf; del_xDotf]; % Constraint vector
                    maxIter = 20;
                    tol = globalVar.userInput.tolerance*10^-2;
                case 'axial2'
                    xFree = [xGuess(1); xGuess(6)];
                    Fx = [del_zf; del_xDotf]; % Constraint vector
                    maxIter = 20;
                    tol = globalVar.userInput.tolerance*10^-2;
            end
            
            
            while  norm(Fx)>tol
                if iter > maxIter
                    fprintf('\nMaximum number of iterations exceded! \n');
                    isMaxIterReached = 1;
                    break;
                end
                
                % Obtain Solution
                [tb,xb] = integrate(app,globalVar,f1,xGuess,tspan,'crossing');
                
                % Define Constraint Vector
                switch orbit
                    case 'lyapunov'
                        del_yf = xb(end,2) ;
                        del_xDotf =xb(end,4);
                        del_yDotf = xb(end,5); % Constraint vector
                        Fx = del_xDotf;
                    case {'halo','halo2'}
                        del_yf = xb(end,2) ;
                        del_xDotf =xb(end,4) ;
                        del_zDotf = xb(end,6);
                        Fx = [del_xDotf;del_zDotf]; % Constraint vector
                    case {'axial', 'axial2'}
                        del_yf = xb(end,2) ;
                        del_xDotf =xb(end,4) ;
                        del_zf = xb(end,3);
                        Fx = [del_zf; del_xDotf]; % Constraint vector
                end
                
                
                %Get STM
                [t,PHItf,x,xf] = stm_X(app,globalVar,xGuess,f2,tb(end));
                
                %Get the final Vector Field from the EOM
                X_DotDotf = CR3BP([],xb(end,:),globalVar.userInput.mu);
                
                %Perform Differential Correction based on orbital case
                switch orbit
                    case 'lyapunov'
                        y_Dotf = X_DotDotf(2);
                        x_DotDotf = X_DotDotf(4);
                        DF = (PHItf(4,5)*y_Dotf - PHItf(2,5)*x_DotDotf)/y_Dotf;
                        xFree = xFree - (1/DF)*Fx;
                        % Guess Update
                        xGuess(5) = xFree; % Corrected yDot value
                    case 'halo'
                        y_Dotf = X_DotDotf(2);
                        x_DotDotf = X_DotDotf(4);
                        z_DotDotf = X_DotDotf(6);
                        DF = ([PHItf(4,1) PHItf(4,5);PHItf(6,1) PHItf(6,5)] - (1/y_Dotf)*[x_DotDotf;z_DotDotf]*[PHItf(2,1) PHItf(2,5)]);
                        DFn = -DF\Fx;
                        xFree = xFree + DFn;
                        % Guess Update
                        xGuess(1) = xFree(1);
                        xGuess(5) = xFree(2);
                    case 'halo2'
                        y_Dotf = X_DotDotf(2);
                        x_DotDotf = X_DotDotf(4);
                        z_DotDotf = X_DotDotf(6);
                        DF = ([PHItf(4,3) PHItf(4,5);PHItf(6,3) PHItf(6,5)] - (1/y_Dotf)*[x_DotDotf;z_DotDotf]*[PHItf(2,3) PHItf(2,5)]);
                        DFn = -DF\Fx;
                        xFree = xFree + DFn;
                        % Guess Update
                        xGuess(3) = xFree(1);
                        xGuess(5) = xFree(2);
                    case 'axial'
                        y_Dotf = X_DotDotf(2);
                        x_DotDotf = X_DotDotf(4);
                        z_Dotf = X_DotDotf(3);
                        DF = ([PHItf(3,1) PHItf(3,5);PHItf(4,1) PHItf(4,5)] - (1/y_Dotf)*[z_Dotf;x_DotDotf]*[PHItf(2,1) PHItf(2,5)]);
                        DFn = -DF\Fx;
                        xFree = xFree + DFn;
                        % Guess Update
                        xGuess(1) = xFree(1);
                        xGuess(5) = xFree(2);
                    case 'axial2'
                        y_Dotf = X_DotDotf(2);
                        x_DotDotf = X_DotDotf(4);
                        z_Dotf = X_DotDotf(3);
                        DF = ([PHItf(3,1) PHItf(3,6);PHItf(4,1) PHItf(4,6)] - (1/y_Dotf)*[z_Dotf;x_DotDotf]*[PHItf(2,1) PHItf(2,6)]);
                        DFn = -DF\Fx;
                        xFree = xFree + DFn;
                        % Guess Update
                        xGuess(1) = xFree(1);
                        xGuess(6) = xFree(2);
                end
                
                iter = iter + 1;
                
            end
            %Assign outputs
            xCorrec = xGuess;
            tCorrec = 2*tb(end);
        end
        
        function[xGuess] = initGuess(app,globalVar)
            % Inputs
            % ------
            %1) globalVar - Global Variable
            %
            % Outputs
            % --------
            % 1) XGuess(1 and 2) - Inital Solution Guesses
            
            %Pull Data
            lagrangePt = globalVar.userInput.lagrangePt;
            orbit = globalVar.userInput.orbit;
            type = globalVar.userInput.type;
            
            Ax1 = globalVar.const.Ax1;
            Ax2 = globalVar.const.Ax2;
            mud = globalVar.userInput.mu;
            
            %Compute initial guesses using method from Dynamical Systems,the Three Body Problem and Space Mission Design
            switch orbit
                case 'lyapunov'
                    if lagrangePt == 2
                        Ax1 = -Ax1;
                        Ax2 = -Ax2;
                    end
                    x_e = globalVar.lagPts.pos(lagrangePt);
                    mu_bar = mud*abs(x_e(1) -1+mud)^-3 + (1-mud)*abs(x_e(1) + mud)^-3 ;
                    nu = sqrt(-0.5*(mu_bar-2-sqrt(9*mu_bar^2-8*mu_bar)));
                    Tau = -(nu^2 + 2*mu_bar+1)/(2*nu);
                    nu_y0.one = -Ax1*nu*Tau;
                    nu_y0.two = -Ax2*nu*Tau;
                    xGuess(1,:) = [(x_e(1)-Ax1),0, 0, 0,nu_y0.one, 0];
                    xGuess(2,:) = [(x_e(1)-Ax2),0, 0, 0,nu_y0.two, 0];
            end
            
        end
        function [jacobiConst] = jacobiValue3D(app,X,mud)
            % Inputs
            % ------
            %    X  - Current States
            %    mud - Gravitational Parameter Value
            %
            % Outputs
            % -------
            %    jacobiConst - Energy Value
            
            %Determine if 2D or 3D
            if length(X)>4
                type = 'ThreeDim';
            else
                type = 'Planar';
            end
            
            %Compute value
            switch type
                case 'Planar'
                    x=X(1);
                    y=X(2);
                    
                    xDot=X(3);
                    yDot=X(4);
                    
                    r=sqrt(((x-1+mud).^2)+(y.^2));
                    d=sqrt(((x+mud).^2)+(y.^2));
                    jacobiConst = (x.^2)+(y.^2) +(2*(1-mud)./d)+(2*mud./r) - ((xDot.^2)+(yDot.^2));
                    
                case 'ThreeDim'
                    x=X(1);
                    y=X(2);
                    z=X(3);
                    xDot=X(4);
                    yDot=X(5);
                    zDot=X(6);
                    
                    r=sqrt(((x-1+mud).^2)+(y.^2)+(z.^2));
                    d=sqrt(((x+mud).^2)+(y.^2)+(z.^2));
                    jacobiConst = (x.^2)+(y.^2) +(2*(1-mud)./d)+(2*mud./r) - ((xDot.^2)+(yDot.^2)+(zDot.^2));
            end
            
        end
        
        function [t,x] = integrate(app,globalVar,fun,x0,tspan,type,extra)
            % Inputs
            % ------
            % 1) globalVar - Global data.
            % 2) fun - Function to Integrate
            % 3) x0 - Initial Condition
            % 4) tspan - Time span
            % 5) type - Type of Integration
            % 6) extra - Direction of Integration
            %
            % Outputs
            % --------
            % 1) [t,x] - Output of ODE113 function
            
            %Setup
            if nargin<3
                disp('provide function and/or initial condition')
            elseif nargin == 3
                tspan = [0 10];
                type = 'forward';
                
            elseif nargin == 4
                type = 'forward';
            end
            
            %Choose type of integration
            switch type
                case 'forward' %Standard forward integration
                    options  = globalVar.functions.odeOptions;
                    [t,x]    = ode113(fun,tspan,x0,options);
                    
                case 'backward' %Standard backwards integration
                    options = globalVar.functions.odeOptions;
                    [t,x]   = ode113(fun,-tspan,x0,options);
                    
                case 'crossing' %Used for differential corrector
                    eventFunc = @(t,x) event_yCrossing(t,x,globalVar);
                    options   = odeset('Reltol',1e-12,'Abstol',1e-12,'Events',eventFunc);
                    [t,x]     = ode113(fun,tspan,x0,options);
                    
                case 'Poincare_2/3' %U2/U3 Poincare Crossing as endpoint
                    if extra == -1
                        tspan = -tspan;
                    end
                    eventFunc = @(t,x) event_xCrossing(t,x);
                    options   = odeset('Reltol',1e-12,'Abstol',1e-12,'Events',eventFunc);
                    [t,x]     = ode113(fun,tspan,x0,options);
                    
                case 'Poincare_1/4' %U1/U4 Poincare Crossing as endpoint
                    if extra == -1
                        tspan = -tspan;
                    end
                    eventFunc = @(t,x) event_Crossing_U1_U4_Poinc(t,x);
                    options   = odeset('Reltol',1e-12,'Abstol',1e-12,'Events',eventFunc);
                    [t,x]     = ode113(fun,tspan,x0,options);
            end
            
            %Define event functions
            function [position,isterminal,direction] = event_xCrossing(t,x)
                
                if abs(t)>1e-6 && abs(x(2)) < 0.5 %No cross near t=0 and ignore points that are coming from the outer region as causes false ending
                    isterminal = 1;
                else
                    isterminal = 0;
                end
                position = x(1) - (1-app.mu);
                
                direction = 0;
                
            end
            
            function [position,isterminal,direction] = event_Crossing_U1_U4_Poinc(t,x)
                
                if x(1) < -app.mu %Check if we are behind M1
                    isterminal = 1;
                else
                    isterminal = 0;
                end
                
                position = x(2);
                
                direction = 0;
                
            end
            
            
        end


        function [sn,un,cn,pn,Ws,Wu,Wc,Wp,V,D] = calcEigen(app,A,discrete)
            % Compute the eigenvalues and eigenvectors of the matrix A spanning the three local subspaces <Es,Eu,Ec> where A is MxM and s+u+c=M, which locally approximate the invariant manifolds <Ws,Wu,Wc>
            % Shane Ross (revised 2.19.04)
            % This is taken from SD Ross website available for free.
            
            sn=[];
            un=[];
            cn=[];
            pn=[];
            Ws=[];
            Wu=[];
            Wc=[];
            Wp=[];
            
            % arbitrary small displacement for use in discrete case
            %delta = 1.e-4;  % <== maybe needs tweeking?
            [M,~]=size(A);
            [V,D]  =eig(A); % obtain eigenvectors (V) and eigenvalues (D) of matrix A
            V = cleanUpMatrix(V) ;
            D = cleanUpMatrix(D) ;
            delta = min(nonzeros(abs(D-eye(M))'))+1e-6;
            deltaImag = 1.e-3;
            
            
            s=0;u=0;c=0;p=0;
            
            for k = 1:M
                if     discrete == 1             % discrete time system
                    if    abs(D(k,k)) <1 && D(k,k)-1 < -delta && s==0 && abs(imag(D(k,k))) < delta
                        s=s+1;
                        sn(s)   = D(k,k);
                        jj=1; while abs(V(jj,k)) == 0, jj=jj+1; end
                        Ws(:,s) = V(:,k)./norm(V((1:3),k));% stable   (s dimensional)
                        Ws(1:M,s) = RemoveInfinitesimals(app,Ws(:,s));
                    elseif abs(D(k,k)-1) >  delta && u==0 && abs(imag(D(k,k))) < delta
                        u=u+1;
                        un(u)   = D(k,k);
                        jj=1; while abs(V(jj,k)) == 0, jj=jj+1; end
                        Wu(:,u) = V(:,k)./norm(V((1:3),k));% unstable (u dimensional)
                        Wu(1:M,u) = RemoveInfinitesimals(app,Wu(:,u));
                    elseif abs(abs(D(k,k))-1) > -delta && abs(abs(D(k,k))-1) <  delta && abs(imag(D(k,k))) < deltaImag
                        p=p+1;
                        pn(p)   = D(k,k);
                        jj=1; while abs(V(jj,k)) == 0, jj=jj+1; end
                        Wp(:,p) = V(:,k)./norm(V((1:3),k));% periodic   (p dimensional)
                        Wp(1:M,p) = RemoveInfinitesimals(app,Wp(:,p));
                    else
                        c=c+1;
                        cn(c)   = D(k,k);
                        jj=1; while abs(V(jj,k)) == 0, jj=jj+1; end
                        Wc(:,c) = V(:,k)./norm(V((1:3),k));% center   (c dimensional)
                        Wc(1:M,c) = RemoveInfinitesimals(app,Wc(:,c));
                    end
                elseif discrete == 0	         % continuous time system
                    if     real(D(k,k)) < 0
                        s=s+1;
                        sn(s)   = D(k,k);
                        jj=1; while abs(V(jj,k)) == 0, jj=jj+1; end
                        %Ws(:,s) = V(:,k)./V(jj,k);% stable   (s dimensional)
                        Ws(:,s) = V(:,k)./norm(V((1:3),k));
                        Ws(1:M,s) = RemoveInfinitesimals(app,Ws(:,s));
                    elseif real(D(k,k)) > 0
                        u=u+1;
                        un(u)   = D(k,k);
                        jj=1; while abs(V(jj,k)) == 0, jj=jj+1; end
                        %Wu(:,u) = V(:,k)./V(jj,k);% unstable (u dimensional)
                        Wu(:,u) = V(:,k)./norm(V((1:3),k));
                        Wu(1:M,u) = RemoveInfinitesimals(app,Wu(:,u));
                    else
                        c=c+1;
                        cn(c)   = D(k,k);
                        jj=1; while abs(V(jj,k)) == 0, jj=jj+1; end
                        %Wc(:,c) = V(:,k)./V(jj,k);% center   (c dimensional)
                        Wc(:,c) = V(:,k)./norm(V((1:3),k));
                        Wc(1:M,c) = RemoveInfinitesimals(app,Wc(:,c));
                    end
                end
            end
            
            function A = cleanUpMatrix(A)
                
                TOL=1.e-14;
                
                for k=1:length(A)
                    for l = 1:length(A)
                        if abs(real(A(k,l))) < TOL
                            A(k,l)=1i*imag(A(k,l)) ;
                        end
                        if abs(imag(A(k,l))) < TOL
                            A(k,l)=real(A(k,l)) ;
                        end
                    end
                end


                function A=RemoveInfinitesimals(A)
                    
                    % Remove all entries with absolute value smaller than TOL
                    TOL=1.e-14;
                    
                    for k=1:length(A)
                        if abs(real(A(k))) < TOL
                            A(k)=A(k) - real(A(k));
                        end
                        if abs(imag(A(k))) < TOL
                            A(k)=A(k) - 1i*imag(A(k));
                        end
                    end
                    
                    
                end
            end
        end
        
        function stabilityIdx = calcStabilityIdx(app,eigens)
            % Inputs
            % ------
            % 1) eigens   - Eigenvalues of STM Matrix.
            %
            % Outputs
            % --------
            % 1) stabilitIdx - Stability Index
            %
            
            stabilityIdx.saddle = 1/2*(abs(eigens.val.stable) + abs(eigens.val.unstable));
            for i = 1:length(eigens.val.center)/2
                stabilityIdx.center(i) = 1/2*(eigens.val.center(2*i-1)+eigens.val.center(2*i));
            end
            if ~isempty(eigens.val.p)
                stabilityIdx.p = 1/2*(eigens.val.p(1)+eigens.val.p(2));
            else
                stabilityIdx.p = NaN;
            end
        end
        
        function X_Dot = CR3BP(t,X)
            % Inputs
            % ------
            % 1) x   - Current State.
            %
            % Outputs
            % --------
            % 1) X_Dot - Change in X per the EOM
            %
            %Check if 2D or 3D
            if length(X)>4
                type = 'ThreeDim';
            else
                type = 'Planar';
            end
            
            %Based on above compute change in states per the EOM
            switch type
                case 'Planar'
                    % Unpack the state variables
                    x = X(1);
                    y = X(2);
                    
                    mu1 = 1-app.mu;
                    mu2 = app.mu;
                    
                    %Distances to the primaries
                    d3= ((x+mu2)^2 + y^2)^1.5;
                    r3= ((x-mu1)^2 + y^2 )^1.5;
                    
                    %Partial Derivative of the Effective Potential Function
                    Ux =  x - mu1*(x+mu2)/d3 - mu2*(x-mu1)/r3 ;
                    Uy =  y - mu1* y/d3 - mu2* y/r3 ;
                    
                    xDot  = X(3);
                    yDot  = X(4);
                    
                    xDDot = 2*yDot + Ux;
                    yDDot = -2*xDot + Uy;
                    
                    X_Dot = [xDot;yDot;xDDot;yDDot];
                    
                    %============================================================================
                case 'ThreeDim'
                    % Unpack the state variables
                    x = X(1);
                    y = X(2);
                    z = X(3);
                    
                    mu1 = 1-app.mu;
                    mu2 = app.mu;
                    
                    %Distances to the primaries
                    d3= ((x+mu2)^2 + y^2 + z^2)^1.5;
                    r3= ((x-mu1)^2 + y^2 + z^2 )^1.5;
                    
                    % Partial Derivative of the Effective Potential Function
                    Ux =  x - mu1*(x+mu2)/d3 - mu2*(x-mu1)/r3 ;
                    Uy =  y - mu1* y/d3 - mu2* y/r3 ;
                    Uz = -mu1*z/d3 - mu2*z/r3;
                    
                    xDot  = X(4);
                    yDot  = X(5);
                    zDot  = X(6);
                    xDDot = 2*yDot + Ux;
                    yDDot = -2*xDot + Uy;
                    zDDot = Uz;
                    
                    X_Dot = [xDot;yDot;zDot;xDDot;yDDot;zDDot];
                    
            end
            
        end
        
        function globalVar = getGlobalVariable(app,userInput)
            % Inputs
            % ------
            % 1) userInput   - User input created in the dashboard function call.
            %
            % Outputs
            % --------
            % 1) globalVar - Global Variable


            globalVar.userInput             = userInput; %Store in the event we want to access again
            globalVar.const.gam             = (userInput.mu/3)^(1/3) ; %Compute gamma
            globalVar.const.Ax1             = 2e-2*globalVar.const.gam;%Amplitude for Initial Guess
            globalVar.const.Ax2             = 2*globalVar.const.Ax1; %Amplitude for Second Initial Guess
            if userInput.lagrangePt == 3
                globalVar.const.Ax2             = 5*globalVar.const.Ax1; %Second Amplitude for L3 point
            end
            globalVar.lagPts                    = equilPts(app,app.mu); %Find location of equillibrium points
            
            globalVar.const.jacobianMax       = globalVar.lagPts.jacobianConst(userInput.lagrangePt); %Find Jacobian Limit
            
            globalVar.functions.odeOptions      = odeset('Reltol',3.e-14,'Abstol',1.e-16);
            globalVar.functions.odeOptionssol      = odeset('Reltol',3.e-14,'Abstol',1.e-16,'Events',@event_xCrossing);
            globalVar.functions.systemDynamics  = @(t,x) CR3BP(t,x,userInput.mu);
            globalVar.functions.varEq_stmDot    = @(t,x) varEq_stmDot(t,x,userInput.mu);
            
            globalVar.FncOutput.invManifoldIC_Pos = []; %Create for storage
            globalVar.FncOutput.invManifoldIC_Neg = []; %Create for storage
            
        end
        
        function [varDot] = varEq_stmDot(app,t,varInit,mud)
            % Inputs
            % ------
            % 1) t   - time
            % 2) varInit   - Initial STM value
            % 3) mud   - Gravitational Parameter
            %
            % Outputs
            % --------
            % 1) varDot - Change in STM
            
            
            N = length(varInit);
            
            if N == 20
                type = 'Planar';
            elseif N == 42
                type = 'ThreeDim';
            end
            
            switch type
                case 'ThreeDim'
                    
                    X =  varInit(37:end);
                    Phi0 = reshape(varInit(1:36),6,6);
                    Df = Dfmatrix3D(app,X,mud);
                    phidot = Df*Phi0;
                    PhiDot(1:36) = reshape(phidot,36,1);
                    PhiDot(37:42) = CR3BP([],X,mud);
                    
                    varDot = PhiDot';
                    
                case 'Planar'
                    
                    X =  varInit(17:end);
                    Phi0 = reshape(varInit(1:16),4,4);
                    Df = Dfmatrix3D(app,X,mud);
                    phidot = Df*Phi0;
                    PhiDot(1:16) = reshape(phidot,16,1);
                    PhiDot(17:20) = CR3BP(t,X,mud);
                    
                    varDot = PhiDot';
            end
            
        end
        
        function[lagPts]= equilPts(app,mud)
            % Inputs
            % ------
            % 1) mud   - Gravitational Parameter
            %
            % Outputs
            % --------
            % 1) lagPts - Lagrange point locations, jacobian constants, and eigenvalues


            mu2=1-mud;
            
            poly1 = [1   -1*(3-mud)  (3-2*mud)  -mud   2*mud  -mud ];
            poly2 = [1      (3-mud)  (3-2*mud)  -mud  -2*mud  -mud ];
            poly3 = [1      (2+mud)  (1+2*mud)  -mu2 -2*mu2 -mu2];
            
            rt1 = roots(poly1);
            rt2 = roots(poly2);
            rt3 = roots(poly3);
            
            for k=1:5
                if isreal(rt1(k))
                    gamma1=rt1(k);
                end
                if isreal(rt2(k))
                    gamma2=rt2(k);
                end
                if isreal(rt3(k))
                    gamma3=rt3(k);
                end
                
            end
            lagPts.gamma = [gamma1;gamma2;gamma3];
            
            xL1 = 1-mud-gamma1;
            xL2 = 1-mud+gamma2;
            xL3 = -mud-gamma3;
            xL4 = 0.5-mud;
            xL5 = xL4;
            yL4 = sqrt(3)/2;
            yL5 = -yL4;
            yL1 = 0;
            yL2 = 0;
            yL3 = 0;
            
            % Lagrange Points
            lagPts.pos(1,:) = [xL1,yL1];
            lagPts.pos(2,:) = [xL2,yL2];
            lagPts.pos(3,:) = [xL3,yL3];
            lagPts.pos(4,:) = [xL4,yL4];
            lagPts.pos(5,:) = [xL5,yL5];
            
            
            % Calculate Energy Values
            lagPts.jacobianConst(1) = jacobiValue3D(app,[lagPts.pos(1,1),lagPts.pos(1,2),0,0,0,0],mud);
            lagPts.jacobianConst(2) = jacobiValue3D(app,[lagPts.pos(2,1),lagPts.pos(2,2),0,0,0,0],mud);
            lagPts.jacobianConst(3) = jacobiValue3D(app,[lagPts.pos(3,1),lagPts.pos(3,2),0,0,0,0],mud);
            lagPts.jacobianConst(4) = jacobiValue3D(app,[lagPts.pos(4,1),lagPts.pos(4,2),0,0,0,0],mud);
            lagPts.jacobianConst(5) = jacobiValue3D(app,[lagPts.pos(5,1),lagPts.pos(5,2),0,0,0,0],mud);
            
            % Find Eigenvalues/vectors
            [lagPts.eig.Val.stable.L1,lagPts.eig.Val.unstable.stable.L1,lagPts.eig.Val.center.L1,lagPts.eig.Vec.stable.L1,lagPts.eig.Vec.unstable.L1,lagPts.eig.Vec.center.L1,~,~] =...
                calcEigen(app,Dfmatrix3D(app,[lagPts.pos(1,1),lagPts.pos(1,2),0,0,0,0],mud),0);
            [lagPts.eig.Val.stable.L2,lagPts.eig.Val.unstable.stable.L2,lagPts.eig.Val.center.L2,lagPts.eig.Vec.stable.L2,lagPts.eig.Vec.unstable.L2,lagPts.eig.Vec.center.L2,~,~] =...
                calcEigen(app,Dfmatrix3D(app,[lagPts.pos(2,1),lagPts.pos(2,2),0,0,0,0],mud),0);
            [lagPts.eig.Val.stable.L3,lagPts.eig.Val.unstable.stable.L3,lagPts.eig.Val.center.L3,lagPts.eig.Vec.stable.L3,lagPts.eig.Vec.unstable.L3,lagPts.eig.Vec.center.L3,~,~] =...
                calcEigen(app,Dfmatrix3D(app,[lagPts.pos(3,1),lagPts.pos(3,2),0,0,0,0],mud),0);
            [lagPts.eig.Val.stable.L4,lagPts.eig.Val.unstable.stable.L4,lagPts.eig.Val.center.L4,lagPts.eig.Vec.stable.L4,lagPts.eig.Vec.unstable.L4,lagPts.eig.Vec.center.L4,~,~] =...
                calcEigen(app,Dfmatrix3D(app,[lagPts.pos(4,1),lagPts.pos(4,2),0,0,0,0],mud),0);
            [lagPts.eig.Val.stable.L5,lagPts.eig.Val.unstable.stable.L5,lagPts.eig.Val.center.L5,lagPts.eig.Vec.stable.L5,lagPts.eig.Vec.unstable.L5,lagPts.eig.Vec.center.L5,~,~] =...
                calcEigen(app,Dfmatrix3D(app,[lagPts.pos(1,1),lagPts.pos(1,2),0,0,0,0],mud),0);
        end
        
        function Df = Dfmatrix3D(app,x,mud)
            % Inputs
            % ------
            % 1) mud   - Gravitational Parameter
            %
            % Outputs
            % --------
            % 1) lagPts - Lagrange point locations, jacobian constants, and eigenvalues
            
            
            %Determind if 2D or 3D
            if length(x)>4
                type = '3D';
            else
                type = '2D';
            end
            
            mu1 = 1-mud;
            mu2 = mud;
            
            switch type
                case '2D'
                    %Distance to masses
                    r2= (x(1)+mu2)^2 + x(2)^2;
                    R2= (x(1)-mu1)^2 + x(2)^2;
                    
                    r3= r2^1.5;
                    r5= r2^2.5;
                    R3= R2^1.5;
                    R5= R2^2.5;
                    
                    %Partial derivatives of the effective potential
                    Uxx = 1 - mu1/r3 + (3*mu1*(x(1)+mud)^2)/r5 - mu2/R3 + (3*mud*(x(1)-1+mud)^2)/R5 ;
                    Uyy = 1 - mu1/r3 + (3*mu1*x(2)^2)/r5 - mu2/R3 + (3*mu2*x(2)^2)/R5 ;
                    Uxy = (3*mu1*(x(1)+mu2)*x(2))/r5 + (3*mu2*(x(1)-1+mu2)*x(2))/R5 ;
                    Uyx = Uxy;
                    
                    %Construct Jacobian Matrix
                    I   = eye(2);
                    zer = zeros(2,2);
                    UXX = [Uxx Uxy; Uyx Uyy];
                    sig = [0 2;-2 0];
                    
                    Df  = [zer I;UXX sig];
                    %==================================================================================
                case '3D'
                    %Distance to masses
                    r2= (x(1)+mu2)^2 + x(2)^2 + x(3)^2;
                    R2= (x(1)-mu1)^2 + x(2)^2 + x(3)^2;
                    
                    r3= r2^1.5;
                    r5= r2^2.5;
                    R3= R2^1.5;
                    R5= R2^2.5;
                    
                    %Partial derivatives of the effective potential
                    Uxx = 1 - mu1/r3 + (3*mu1*(x(1)+mud)^2)/r5 - mu2/R3 + (3*mud*(x(1)-1+mud)^2)/R5 ;
                    Uyy = 1 - mu1/r3 + (3*mu1*x(2)^2)/r5 - mu2/R3 + (3*mu2*x(2)^2)/R5 ;
                    Uzz = -mu1/r3 + (3*mu1*x(3)^2)/r5 - mu2/R3 + (3*mu2*x(3)^2)/R5 ;
                    Uxy = (3*mu1*(x(1)+mu2)*x(2))/r5 + (3*mu2*(x(1)-1+mu2)*x(2))/R5 ;
                    Uyx = Uxy;
                    Uxz = (3*mu1*(x(1)+mu2)*x(3))/r5 + (3*mu2*(x(1)-1+mu2)*x(3))/R5 ;
                    Uzx = Uxz ;
                    Uyz = (3*mu1*x(2)*x(3))/r5 + (3*mu2*x(2)*x(3))/R5 ;
                    Uzy = Uyz ;
                    
                    %Construct Jacobian Matrix
                    I   = eye(3);
                    zer = zeros(3,3);
                    UXX = [Uxx Uxy Uxz; Uyx Uyy Uyz;Uzx Uzy Uzz];
                    sig = [0 2 0;-2 0 0;0 0 0];
                    
                    Df  = [zer I;UXX sig];
            end
            
        end
        function [t,PHItf,x,xf,PHI] = stm_X(app,globalVar,xGuess,fun,tend)
            % Inputs
            % ------
            % 1) mud   - Gravitational Parameter
            % 2) xGuess   - Initial Condition
            % 3) fun   - Function to integrate with
            % 4) tend   - Ending time
            %
            % Outputs
            % --------
            % 1) t - time history
            % 2) PHItf - Final STM
            % 3) x - State History
            % 4) xf - Final State
            % 5) PHI - STM History
            
            
            varEq_initVal = varEq_initialization(app,xGuess); %Get IC
            [tInteg,xInteg] = integrate(app,globalVar,fun,varEq_initVal,[0 tend],'forward',1); %Integrate equations
            
            N = size(xInteg,2);
            if N == 20
                type = 'Planar';
            else
                type = 'ThreeDim';
            end
            
            %Reshape and extract information from the integration
            switch type
                case 'Planar'
                    t = tInteg;
                    PHI = xInteg;
                    PHItf = reshape(xInteg(end,1:16),4,4);
                    x = xInteg(:,17:20);
                    xf = xInteg(end,17:20);
                    
                case'ThreeDim'
                    t = tInteg;
                    PHI = xInteg;
                    PHItf = reshape(xInteg(end,1:36),6,6);
                    x = xInteg(:,37:42);
                    xf = xInteg(end,37:42);
            end
        end
        
        function globalVar = plotInvManifold(app,globalVar)
            % Inputs
            % ------
            % 1) globalVar - Global Variable
            %
            % Outputs
            % --------
            % 1) globalVar - Global Variable
            %
            
            
            switch globalVar.userInput.orbit
                case 'lyapunov'
                    orbPar = lyapunovOrbit(app,globalVar); %Find orbital parameters for lyapunov orbit of desired energy
            end
            
            manifoldType = globalVar.userInput.manifoldType;
            
            switch manifoldType
                case 'stable'
                    propogate = 'backward';
                    extra = -1;
                    STB = 1;
                case 'unstable'
                    propogate = 'forward';
                    extra = 1;
                    STB = -1;
            end
            
            % Initial Condition for invarient manifolds
            invManifoldIC = orbitInvManifoldIC(app,globalVar,orbPar,100, manifoldType,1);
            invManifoldICneg = orbitInvManifoldIC(app,globalVar,orbPar,100, manifoldType,-1);
            
            %Create a vector from IC to the first point in manifoldIC that has the offset
            offset = orbPar.IC - invManifoldIC(1,:);
            %Eigenvector has a habit of switching sometimes based on energy chosen, this reassigns ICs to be on their correct manifolds
            if sign(offset(2)) > 0 && isequal(manifoldType,'stable')
                %Set positive ICs to negative
                temp = invManifoldIC;
                invManifoldIC = invManifoldICneg;
                invManifoldICneg = temp;
            elseif sign(offset(2)) < 0 && isequal(manifoldType,'unstable')
                %Set positive ICs to negative
                temp = invManifoldIC;
                invManifoldIC = invManifoldICneg;
                invManifoldICneg = temp;
            end
            
            %Hold plot if not held
            if ~ishold(app.ManifoldTubes)
                hold(app.ManifoldTubes)
            end
            
            T = 4*orbPar.period;
            
            %Integrate until the first intersections with a Poincare Section, store values, Plot manifolds
            if globalVar.userInput.lagrangePt == 1 && STB == 1
                %Negative Dir goes until y = 0 && x < -mu
                for i = 1:length(invManifoldIC(:,1))
                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,invManifoldIC(i,1:6),[0 T],'Poincare_2/3', extra);
                    plot3(app.ManifoldTubes,x(:,1),x(:,2),zeros(length(x),1),'g');
                    globalVar.FncOutput.invManifoldIC_Pos(i,:) = x(end,:);
                end
                for i = 1:length(invManifoldICneg(:,1))
                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,invManifoldICneg(i,1:6),[0 T],'Poincare_1/4', extra);
                    plot3(app.ManifoldTubes,x(:,1),x(:,2),zeros(length(x),1),'g');
                    globalVar.FncOutput.invManifoldIC_Neg(i,:) = x(end,:);
                end
                
            elseif globalVar.userInput.lagrangePt == 1 && STB == -1
                %Negative DIR goes until y = 0 && x < -mu
                for i = 1:length(invManifoldIC(:,1))
                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,invManifoldIC(i,1:6),[0 T],'Poincare_2/3', extra);
                    plot3(app.ManifoldTubes,x(:,1),x(:,2),zeros(length(x),1),'r');
                    globalVar.FncOutput.invManifoldIC_Pos(i,:) = x(end,:);
                end
                for i = 1:length(invManifoldICneg(:,1))
                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,invManifoldICneg(i,1:6),[0 T],'Poincare_1/4', extra);
                    plot3(app.ManifoldTubes,x(:,1),x(:,2),zeros(length(x),1),'r');
                    globalVar.FncOutput.invManifoldIC_Neg(i,:) = x(end,:);
                end
                
            elseif globalVar.userInput.lagrangePt == 2 && STB == 1
                %Positive DIR goes until y = 0 %% x < -mu
                for i = 1:length(invManifoldIC(:,1))
                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,invManifoldIC(i,1:6),[0 T],'Poincare_1/4', extra);
                    plot3(app.ManifoldTubes,x(:,1),x(:,2),zeros(length(x),1),'g');
                    globalVar.FncOutput.invManifoldIC_Pos(i,:) = x(end,:);
                end
                for i = 1:length(invManifoldICneg(:,1))
                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,invManifoldICneg(i,1:6),[0 T],'Poincare_2/3', extra);
                    plot3(app.ManifoldTubes,x(:,1),x(:,2),zeros(length(x),1),'g');
                    globalVar.FncOutput.invManifoldIC_Neg(i,:) = x(end,:);
                end
                
            else
                %Positive Dir goes until y = 0 && x < -mu
                for i = 1:length(invManifoldIC(:,1))
                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,invManifoldIC(i,1:6),[0 T],'Poincare_1/4', extra);
                    plot3(app.ManifoldTubes,x(:,1),x(:,2),zeros(length(x),1),'r');
                    globalVar.FncOutput.invManifoldIC_Pos(i,:) = x(end,:);
                end
                for i = 1:length(invManifoldICneg(:,1))
                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,invManifoldICneg(i,1:6),[0 T],'Poincare_2/3', extra);
                    plot3(app.ManifoldTubes,x(:,1),x(:,2),zeros(length(x),1),'r');
                    globalVar.FncOutput.invManifoldIC_Neg(i,:) = x(end,:);
                end
                
            end


            %Plot planetary body locations
            scatter3(app.ManifoldTubes,1-globalVar.userInput.mu,0,0,'o','filled');
            scatter3(app.ManifoldTubes,-globalVar.userInput.mu,0,0,'o','filled');
            
            %Plot zero velocity surface
            E_points = csvread('E_points_EM_System.csv');
            %Evaluate x and y points based on step size
            steps = length(E_points);
            x = linspace(-2,2,steps);
            y = linspace(-2,2,steps);
            
            contour(app.ManifoldTubes,x,y,E_points',[globalVar.userInput.E_desired globalVar.userInput.E_desired]);
            
            %Set view to be 2D
            view(app.ManifoldTubes,2);
        end
        
        
        function [Xn] = orbitInvManifoldIC(app,globalVar,orbPar,n,type, dir)
            %Inputs
            %------
            %1) globalVar - Global Variable
            %2) orbPar - Lyapunov orbit parameters
            %3) n - The number initial conditions
            %4) type - Unstable or Stable Manifold
            %5) dir - positive or negative direction
            %
            %Outputs
            %--------
            %1) Xn - Initial Condition Vector
            
            funVarEq = globalVar.functions.varEq_stmDot;
            epsilon = 10^-7; %Found this works well
            
            switch type
                case 'stable'
                    eigVal = orbPar.eigens.val.stable;
                    eigVec = orbPar.eigens.vec.stable;
                case 'unstable'
                    eigVal = orbPar.eigens.val.unstable;
                    eigVec = orbPar.eigens.vec.unstable;
            end
            tf = orbPar.period;
            X0 = orbPar.IC';
            
            %Gen initial eigenvectors
            k = 1;
            Y0_int = eigVec(:,k);
            
            %Get STM along path
            [~,PHItf,~,~,PHI] = stm_X(app,globalVar,X0,funVarEq,tf);
            i = 1;
            
            %Separate lyapunov orbit into n number of ICs, transport to each location and find the offsets
            for m = 1:floor(length(PHI(:,1))/(n-1)):length(PHI(:,1))
                phi = reshape(PHI(m,1:36),6,6);
                X0 = reshape(PHI(m,37:42),6,1);
                Y0 = phi*Y0_int;
                %Normalize Ys0 to 1
                normY0 = norm(Y0(1:3));
                Y0 = Y0/normY0;
                XN(i,:) = X0 + dir*epsilon*Y0;
                i = i+1;
            end
            Xn(:,:,k) = XN(:,:);
        end
        
        
        function [orbPar] = lyapunovOrbit(app,globalVar)
            % Inputs
            % ------
            % 1) globalVar   - Global Variable
            %
            % Outputs
            % --------
            % 1) orbPar - A structure containing
            % - time      - Full Period
            % - IC        - Initial Condition
            % - jacobianConst    - jacobianConst Value
            % - Monodromy - Monodromy matrix
            % - eigens    - Eigen Values and Eigen Vectors
            
            
            % Extract the parameters
            jacobianConstReq = globalVar.userInput.jacobianConst;
            lagrangePt = globalVar.userInput.lagrangePt;
            mud = globalVar.userInput.mu;
            funVarEq = globalVar.functions.varEq_stmDot;
            tol = globalVar.userInput.tolerance;
            
            %Get initial guess
            [xGuess] = initGuess(app,globalVar);
            
            jacobianMax = globalVar.const.jacobianMax;
            
            isOrbitFound = 1;
            
            %Check if the current energy value is above max value
            if jacobianConstReq> jacobianMax
                isOrbitFound = 0;
            else
            end
            
            %Perform differential correction on initial guesses
            for i = 1:2
                [t(i,1),x(i,:),~] = diffCorrec(app,xGuess(i,:),globalVar);
                jacobianConst(i,1) = jacobiValue3D(app,x(i,:),mud);
            end
            
            i = 3;
            jacobianConstMin = 2.6;
            
            %Rearrange ICs if they are in the wrong order
            if jacobianConstReq> jacobianConst(1)
                xSubs(1,:) = x(1,:);
                tSubs(1,1) = t(1,1);
                jacobianConstSubs(1,1) = jacobianConst(1,1);
                
                x(1,:) = x(2,:);
                t(1,1) = t(2,1);
                jacobianConst(1,1) = jacobianConst(2,1);
                
                x(2,:) = xSubs(1,:);
                t(2,1) = tSubs(1,1);
                jacobianConst(2,1) = jacobianConstSubs(1,1);
                
                %Perform numerical continuation
                while jacobianConst(i-1) < jacobianConstReq
                    
                    delta = (x(i-1,:) - x(i-2,:))/2;
                    xGuess = x(i-1,:) + delta;
                    [~,~,~,isMaxIterReached] = diffCorrec(app,xGuess,globalVar);
                    while isMaxIterReached
                        delta = delta/2;
                        xGuess = x(i-1,:) + delta;
                        [~,~,~,isMaxIterReached] = diffCorrec(app,xGuess,globalVar);
                    end
                    [t(i,1),x(i,:),~,~] = diffCorrec(app,xGuess,globalVar);
                    jacobianConst(i,1) = jacobiValue3D(app,x(i,:),mud);
                    %Check if we are at desired energy value
                    if abs(jacobianConst(i)-jacobianConst(i-1)) < tol
                        isOrbitFound = 0;
                        break;
                    end
                    i = i+1;
                end
                
                %Update for next iteration
                xLowerAmp(1,:) = x(end,:); tLowerAmp(1,1) = t(end,1); jacobianConstLowerAmp(1,1) = jacobianConst(end,1);
                xUpperAmp(1,:) = x(end-1,:); tUpperAmp(1,1) = t(end-1,1); jacobianConstUpperAmp(1,1) = jacobianConst(end-1,1);
                
                % Check if jacobianConst is between these two energies
            elseif jacobianConstReq< jacobianConst(2)
                
                %Numerical continuation
                while jacobianConst(i-1) > jacobianConstReq
                    if jacobianConstReq < jacobianConstMin
                        isOrbitFound = 0;
                        break;
                    end
                    
                    delta = (x(i-1,:) - x(i-2,:));
                    xGuess = x(i-1,:) + delta;
                    [~,~,~,isMaxIterReached] = diffCorrec(app,xGuess,globalVar);
                    while isMaxIterReached
                        delta = delta/2;
                        xGuess = x(i-1,:) + delta;
                        [~,~,~,isMaxIterReached] = diffCorrec(app,xGuess,globalVar);
                    end
                    [t(i,1),x(i,:),~,~] = diffCorrec(app,xGuess,globalVar);
                    jacobianConst(i,1) = jacobiValue3D(app,x(i,:),mud);
                    %Check if we are at desired energy value
                    if abs(jacobianConst(i)-jacobianConst(i-1)) < tol
                        isOrbitFound = 0;
                        break;
                    end
                    i = i+1;
                end
                %Update for next iteration
                xLowerAmp(1,:) = x(end-1,:); tLowerAmp(1,1) = t(end-1,1); jacobianConstLowerAmp(1,1) = jacobianConst(end-1,1);
                xUpperAmp(1,:) = x(end,:); tUpperAmp(1,1) = t(end,1); jacobianConstUpperAmp(1,1) = jacobianConst(end,1);
            end
            
            %Once we have found the orbit of desired energy, find necessary values
            switch isOrbitFound
                case 1
                    tol = 1e-10;
                    jacobianConstNew = jacobianConstUpperAmp(1,1);
                    while abs(jacobianConstNew-jacobianConstReq)>tol
                        delta = (xUpperAmp(1,:) - xLowerAmp(1,:))/2;
                        xGuess = xLowerAmp(1,:) + delta;
                        
                        [t,x,~] = diffCorrec(app,xGuess,globalVar);
                        jacobianConstNew = jacobiValue3D(app,x,mud);
                        if jacobianConstNew>jacobianConstReq
                            xLowerAmp(1,:) = x;
                            tLowerAmp(1,1) = t;
                            jacobianConstLowerAmp(1,1) = jacobianConstNew;
                        else
                            xUpperAmp(1,:) = x;
                            tUpperAmp(1,1) = t;
                            jacobianConstUpperAmp(1,1) = jacobianConstNew;
                        end
                    end
                    
                    [~,monodromy,~,~] = stm_X(app,globalVar,x,funVarEq,t);
                    [eigens.val.stable,eigens.val.unstable,eigens.val.center,eigens.val.p,eigens.vec.stable,...
                        eigens.vec.unstable,eigens.vec.center,eigens.vec.p] = calcEigen(app,monodromy,1) ;
                    stabilityIdx = calcStabilityIdx(app,eigens);
                    orbPar.period      = t;
                    orbPar.IC          = x;
                    orbPar.jacobianConst    = jacobianConstNew;
                    orbPar.monodromy = monodromy;
                    orbPar.eigens    = eigens;
                    orbPar.stabilityIdx = stabilityIdx;
                    
                    %Some minor error handling
                case 0
                    
                    if jacobianConstReq> jacobianMax
                        fprintf('Error: The jacobian(jacobianConst) value enetered is greater than the maximum limit for the Libration Point\n')
                    else
                        fprintf('\nError: jacobianConst input is less than the %d.\n',jacobianConst(end));
                    end
                    clear orbPar;
                    orbPar = -1;
            end
            
            
        end
        
        function [Current_Itin,ItinRegion_Start,PoincareSection] = ItinStart(app, Sections, globalVar)
            % Inputs
            % ------
            % 1) globalVar - Global Variable
            % 2) Sections - Initial Poincare Sections
            %
            % Outputs
            % --------
            % 1) Current_Itin - Current Itinerary
            % 2) ItinRegion_Start - Starting Itinerary Region
            % 3) PoincareSection - Current Poincare Section
            %
            %Extract Section Data
            xL1_sp = Sections{1};
            xL1_sn = Sections{2};
            xL1_up = Sections{3};
            xL1_un = Sections{4};
            xL2_sp = Sections{5};
            xL2_sn = Sections{6};
            xL2_up = Sections{7};
            xL2_un = Sections{8};
            
            Current_Itin = zeros(1,3);
            Current_Itin(1) = app.StartingRegion;
            
            %Prompt User for 1st Step
            switch app.StartingRegion
                
                case 'P'
                    NextStep = questdlg('Enter neck and return or proceed through?','Itinerary Select','Return','Proceed','Proceed');
                    
                case 'N'
                    NextStep = questdlg('Proceed to inner or outer region?','Itinerary Select','Proceed to Inner', 'Proceed to Outer','Proceed to Outer');
                    
                case 'O'
                    NextStep = questdlg('Enter neck and return or proceed through?','Itinerary Select','Return','Proceed','Proceed');
            end
            
            Itin_Found = 0;
            trips = 1;
            
            while ~Itin_Found
                
                switch NextStep
                    
                    case 'Return'
                        
                        if trips == 1 %Need to integrate the stable tube until it reaches the next section
                            %Based on starting region, define itinerary and what Poincare section we will intersect
                            if isequal(app.StartingRegion,'P')
                                Tube1 = cleandata(app,xL1_sp,'y');
                                Tube2 = cleandata(app,xL1_up,'y');
                                Current_Itin = [Current_Itin(1);'N';'P'];
                                PoincareSection = 'P2';
                            else
                                Tube1 = cleandata(app,xL2_sn,'y');
                                Tube2 = cleandata(app,xL2_un,'y');
                                Current_Itin = [Current_Itin(1);'N';'O'];
                                PoincareSection = 'P3';
                            end
                            %Integrate stable tubes forward
                            for i = 1:length(Tube1)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_2/3', -1);
                                Tube1(i,:) = x(end,:);
                            end
                        end
                        %Clean data for any outliers
                        Tube1 = cleandata(app,Tube1,'y');
                        Tube2 = cleandata(app,Tube2,'y');
                        
                        %Create geometry that represents the Poincare Section
                        P_poly_1 = polyshape(Tube1(:,2),Tube1(:,5));
                        P_poly_2 = polyshape(Tube2(:,2),Tube2(:,5));
                        
                        hold(app.ItinRegion)
                        
                        %Compute intersection of the Poincare Sections
                        P_Intersect = intersect(P_poly_1,P_poly_2);
                        %If there are vertices, an intersection occurs and we have an itinerary region
                        if ~isempty(P_Intersect.Vertices)
                            ItinRegion_Start = P_Intersect;
                            Itin_Found = 1;
                            
                        else
                            %Integrate until the next crossing of P
                            for i = 1:length(Tube1)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_2/3', -1);
                                Tube1(i,:) = x(end,:);
                            end
                            for i = 1:length(Tube2)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_2/3', 1);
                                Tube2(i,:) = x(end,:);
                            end
                        end
                        trips = trips + 1;
                        
                    case 'Proceed' %Need to limit intersections to happening on the Poincare section of interest, will need to do some additional filtering of values
                        
                        if trips == 1
                            %Based on starting region, define itinerary and what Poincare section we will intersect
                            if isequal(app.StartingRegion,'P') %Look at P2
                                Tube1 = cleandata(app,xL2_sn,'y');
                                Tube2 = cleandata(app,xL1_up,'y');
                                Current_Itin = [Current_Itin(1);'N';'O'];
                                PoincareSection = 'P2';
                            else
                                Tube1 = cleandata(app,xL1_sp,'y');
                                Tube2 = cleandata(app,xL2_un,'y');
                                Current_Itin = [Current_Itin(1);'N';'P'];
                                PoincareSection = 'P3';
                            end
                        end
                        %Clean data for any outliers
                        Tube1 = cleandata(app,Tube1,'y');
                        Tube2 = cleandata(app,Tube2,'y');
                        
                        %Create geometry that represents the Poincare Section
                        P_poly_1 = polyshape(Tube1(:,2),Tube1(:,5));
                        P_poly_2 = polyshape(Tube2(:,2),Tube2(:,5));
                        
                        hold(app.ItinRegion)
                        
                        %Compute intersection of the Poincare Sections
                        P_Intersect = intersect(P_poly_1,P_poly_2);
                        %If there are vertices, an intersection occurs and we have an itinerary region
                        if ~isempty(P_Intersect.Vertices)
                            ItinRegion_Start = P_Intersect;
                            Itin_Found = 1;
                        else
                            %Integrate until the next crossing of P
                            if isequal(PoincareSection,'P2')
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_2/3', -1);
                                    Tube1(i,:) = x(end,:);
                                end
                            else
                                for i = 1:length(Tube2)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_2/3', 1);
                                    Tube2(i,:) = x(end,:);
                                end
                            end
                        end
                        trips = trips + 1;
                        
                    case 'Proceed to Inner' %Look at P1
                        %Based on starting region, define itinerary and what Poincare section we will intersect
                        if trips == 1
                            Tube1 = cleandata(app,xL1_sn,'x');
                            Tube2 = cleandata(app,xL1_un,'x');
                            Current_Itin = [Current_Itin(1);'P';'N'];
                            PoincareSection = 'P1';
                        end
                        %Create geometry that represents the Poincare Section
                        P1_poly_1 = polyshape(Tube1(:,1),Tube1(:,4));
                        P1_poly_2 = polyshape(Tube2(:,1),Tube2(:,4));
                        
                        hold(app.ItinRegion)
                        
                        %Compute intersection of the Poincare Sections
                        P1_Intersect = intersect(P1_poly_1,P1_poly_2);
                        %If there are vertices, an intersection occurs and we have an itinerary region
                        if ~isempty(P1_Intersect.Vertices)
                            ItinRegion_Start = P1_Intersect;
                            Itin_Found = 1;
                        else
                            %Integrate until the next crossing of P
                            for i = 1:length(Tube1)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_1/4', -1);
                                Tube1(i,:) = x(end,:);
                            end
                            for i = 1:length(Tube2)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_1/4', 1);
                                Tube2(i,:) = x(end,:);
                            end
                        end
                        trips = trips + 1;
                        
                    case 'Proceed to Outer' %Look at P4
                        %Based on starting region, define itinerary and what Poincare section we will intersect
                        if trips == 1
                            P1 = figure();
                            Tube1 = cleandata(app,xL2_sp,'x');
                            Tube2 = cleandata(app,xL2_up,'x');
                            Current_Itin = [Current_Itin(1);'O';'N'];
                            PoincareSection = 'P4';
                        end
                        %Create geometry that represents the Poincare Section
                        P4_poly_1 = polyshape(Tube1(:,1),Tube1(:,4));
                        P4_poly_2 = polyshape(Tube2(:,1),Tube2(:,4));
                        
                        hold(app.ItinRegion)
                        %Compute intersection of the Poincare Sections
                        P4_Intersect = intersect(P4_poly_1,P4_poly_2);
                        %If there are vertices, an intersection occurs and we have an itinerary region
                        if ~isempty(P4_Intersect.Vertices)
                            ItinRegion_Start = P4_Intersect;
                            Itin_Found = 1;
                        else
                            %Integrate until the next crossing of P
                            for i = 1:length(Tube1)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_1/4', -1);
                                Tube1(i,:) = x(end,:);
                            end
                            for i = 1:length(Tube2)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_1/4', 1);
                                Tube2(i,:) = x(end,:);
                            end
                        end
                        trips = trips + 1;
                end
            end
            %If there are multiple regions, take the one with the largest area
            if ItinRegion_Start.NumRegions > 1
                Regions = regions(ItinRegion_Start);
                for i = 1:ItinRegion_Start.NumRegions
                    Reg = Regions(i);
                    %Compute the area and take the largest
                    Area(i) = area(Reg);
                end
                [M,I] = max(Area);
                ItinRegion_Start = Regions(I);
            end
            %Plot Itinerary Region
            plot(app.ItinRegion,ItinRegion_Start,'FaceColor',[0.4940 0.1840 0.5560]);
        end
        
        function FindItineraryRegion(app,Current_Itin,ItineraryRegion,Sections,globalVar)
            % Inputs
            % ------
            % 1) globalVar - Global Variable
            % 2) Sections - Initial Poincare Sections
            % 3) ItineraryRegion - Current Itinerary Region
            % 4) Current_Itin - Current Itinerary
            %
            % Outputs
            % --------
            % Plot and IC selection on dashboard
            %
            %Extract Data
            mu1 = 1-app.mu;
            mu2 = app.mu;
            
            Itin_Complete = 0;
            
            xL1_sp = Sections(1);
            xL1_sn = Sections(2);
            xL1_up = Sections(3);
            xL1_un = Sections(4);
            xL2_sp = Sections(5);
            xL2_sn = Sections(6);
            xL2_up = Sections(7);
            xL2_un = Sections(8);
            
            %Prompt if we are done with design, if not determine where we can reach based on current intinerary
            Done = questdlg('Design Complete?','Yes','No');
            switch Done
                case 'Yes'
                    Itin_Complete = 1;
                    
                    if isequal(Current_Itin',['P','N','O'])
                        app.PoincSect = 'P2';
                    elseif isequal(Current_Itin',['P','N','P'])
                        app.PoincSect = 'P2';
                    elseif isequal(Current_Itin',['O','N','O'])
                        app.PoincSect = 'P3';
                    elseif isequal(Current_Itin',['O','N','P'])
                        app.PoincSect = 'P3';
                    elseif isequal(Current_Itin',['N','O','N'])
                        app.PoincSect = 'P4';
                    elseif isequal(Current_Itin',['N','P','N'])
                        app.PoincSect = 'P1';
                    end
                    
                case 'No'
                    Itin_Complete = 0;
            end
            
            
            %Based on itinerary, determine what next steps can be taken
            while ~Itin_Complete
                
                switch Current_Itin(end)
                    
                    case 'P'
                        NextStep = questdlg('Remain in Region or Proceed to Neck?','Itinerary Select','Remain','Proceed','Proceed');
                        
                    case 'N'
                        NextStep = questdlg('Remain in Region or Proceed to next region (Inner/Outer)?','Itinerary Select','Remain','Proceed to Inner', 'Proceed to Outer','Proceed to Outer');
                        
                    case 'O'
                        NextStep = questdlg('Remain in Region or Proceed to Neck?','Itinerary Select','Remain','Proceed','Proceed');
                        
                end
                
                %Get the largest region of the itinerary if there are multiple regions
                if ItineraryRegion.NumRegions ~=1
                    regs = regions(ItineraryRegion);
                    for i = 1:ItineraryRegion.NumRegions
                        %Compute the area
                        reg = regs(i);
                        areas(i) = area(reg);
                    end
                    [maxarea,ind] = max(areas);
                    ItineraryRegion = regs(ind);
                end
                
                switch NextStep
                    case 'Remain'
                        %Continue Integration of Tubes until the next Poincare Section, check if there is an overlapping region
                        %Prompt for number of orbits, check if that is doable
                        NumOrbits = inputdlg('How many orbits are desired?','Orbit Input');
                        %Now with number of orbits, we need to find a solution region that gets that many orbits


                    case 'Proceed'
                        %Based on Itinerary, Pick Poincare Section and tube to integrate
                        if isequal(Current_Itin(end-1:end)',['N','O'])
                            app.PoincSect = 'P4';
                            
                            Tube1 = xL2_sp;
                            if isequal(Current_Itin(end-2:end)',['P','N','O'])
                                InitSec = 'P2';
                            elseif isequal(Current_Itin(end-2:end)',['O','N','O'])
                                InitSec = 'P3';
                            end
                            
                            Tube2 = cvrt_IC(app,ItineraryRegion.Vertices,InitSec); %Need to convert these to 3-D points
                            Mod = 'Unstable';
                            
                            %Call function to evaluate Poincare Section
                            ItineraryRegion = PoincareEvaluate(app,Tube1,Tube2,app.PoincSect,globalVar,Mod);
                            InitialStep = 0;
                            ItineraryStep = 'N';
                            %Prompt if done with design
                            Done = questdlg('Design Complete?','Yes','No');
                            switch Done
                                case 'Yes'
                                    Itin_Select = 1;
                                case 'No'
                                    Itin_Select = 0;
                                    Current_Itin = [Current_Itin; 'N'];
                            end
                            
                            %Based on Itinerary, Pick Poincare Section and tube to integrate
                        elseif isequal(Current_Itin(end-1:end)',['N','P'])
                            app.PoincSect = 'P1';
                            
                            Tube1 = xL1_sn;
                            if isequal(Current_Itin(end-2:end)',['P','N','P'])
                                InitSec = 'P2';
                                %Can scale itinerary by a small amount to avoid edge cases where trajectory departs the tube
                                [x,y] = centroid(ItineraryRegion);
                                ItineraryRegion = scale(ItineraryRegion,0.99,[x,y]);
                            elseif isequal(Current_Itin(end-2:end)',['O','N','P'])
                                InitSec = 'P3';
                            end
                            Tube2 = cvrt_IC(app,ItineraryRegion.Vertices,InitSec);
                            Mod = 'Unstable';
                            
                            %Call function to evaluate Poincare Section
                            ItineraryRegion = PoincareEvaluate(app,Tube1,Tube2,app.PoincSect,globalVar,Mod);
                            InitialStep = 0;
                            ItineraryStep = 'N';
                            %Prompt if done with design
                            Done = questdlg('Design Complete?','Yes','No');
                            switch Done
                                case 'Yes'
                                    Itin_Select = 1;
                                case 'No'
                                    Itin_Select = 0;
                                    Current_Itin = [Current_Itin; 'N'];
                                    if length(ItineraryRegion.Vertices) < 30 %If we have a low number of points on the Poincare Section call this to generate more
                                        p = interparc(app,150,ItineraryRegion.Vertices(:,1),ItineraryRegion.Vertices(:,2));
                                        ItineraryRegion = polyshape(p,'Simplify',false);
                                    end
                            end
                        end
                        
                        
                    case 'Proceed to Inner'
                        %Based on Itinerary, Pick Poincare Section and tube to integrate
                        if isequal(Current_Itin(end-1:end)',['P','N'])
                            app.PoincSect = 'P3';
                            if length(ItineraryRegion.Vertices) < 35 %If we have a low number of points on the Poincare Section call this to generate more
                                Points = ItineraryRegion.Vertices;
                                Points = [Points;Points(1,:)];
                                p = interparc(app,150,Points(:,1),Points(:,2),'linear');
                                ItineraryRegion = polyshape(p,'Simplify',false);
                                [x,y] = centroid(ItineraryRegion);
                                ItineraryRegion = scale(ItineraryRegion,0.95,[x,y]);
                            end
                            Tube1 = cvrt_IC(app,ItineraryRegion.Vertices,'P1');
                            Tube2 = xL1_sp;
                            Mod = 'Stable';
                            %Call function to evaluate Poincare Section
                            ItineraryRegion = PoincareEvaluate(app,Tube1,Tube2,app.PoincSect,globalVar,Mod);
                            InitialStep = 0;
                            ItineraryStep = 'N';
                            %Prompt if done with design
                            Done = questdlg('Design Complete?','Yes','No');
                            switch Done
                                case 'Yes'
                                    Itin_Select = 1;
                                case 'No'
                                    Itin_Select = 0;
                                    Current_Itin = [Current_Itin; 'P'];
                            end
                            
                            %Based on Itinerary, Pick Poincare Section and tube to integrate
                        elseif isequal(Current_Itin(end-1:end)',['O','N'])
                            app.PoincSect = 'P2';
                            if length(ItineraryRegion.Vertices) < 30 %If we have a low number of points on the Poincare Section call this to generate more
                                Points = ItineraryRegion.Vertices;
                                Points = [Points;Points(1,:)];
                                p = interparc(app,150,Points(:,1),Points(:,2),'linear');
                                ItineraryRegion = polyshape(p,'Simplify',false);
                                [x,y] = centroid(ItineraryRegion);
                                ItineraryRegion = scale(ItineraryRegion,0.98,[x,y]);
                            end
                            Tube1 = cvrt_IC(app,ItineraryRegion.Vertices,'P4');
                            Tube2 = xL1_up;
                            Mod = 'Stable';
                            %Call function to evaluate Poincare Section
                            ItineraryRegion = PoincareEvaluate(app,Tube1,Tube2,app.PoincSect,globalVar,Mod);
                            InitialStep = 0;
                            ItineraryStep = 'N';
                            %Prompt if done with design
                            Done = questdlg('Design Complete?','Yes','No');
                            switch Done
                                case 'Yes'
                                    Itin_Select = 1;
                                case 'No'
                                    Itin_Select = 0;
                                    Current_Itin = [Current_Itin; 'P'];
                            end
                        end
                        
                    case 'Proceed to Outer'
                        %%Based on Itinerary, Pick Poincare Section and tube to integrate
                        if isequal(Current_Itin(end-1:end)',['P','N'])
                            app.PoincSect = 'P3';
                            if length(ItineraryRegion.Vertices) < 30 %If we have a low number of points on the Poincare Section call this to generate more
                                Points = ItineraryRegion.Vertices;
                                Points = [Points;Points(1,:)];
                                p = interparc(app,150,Points(:,1),Points(:,2),'linear');
                                ItineraryRegion = polyshape(p,'Simplify',false);
                                [x,y] = centroid(ItineraryRegion);
                                ItineraryRegion = scale(ItineraryRegion,0.98,[x,y]);
                            end
                            Tube1 = cvrt_IC(app,ItineraryRegion.Vertices,'P1');
                            Tube2 = xL2_un;
                            Mod = 'Stable';
                            %Call function to evaluate Poincare Section
                            ItineraryRegion = PoincareEvaluate(app,Tube1,Tube2,app.PoincSect,globalVar,Mod);
                            InitialStep = 0;
                            ItineraryStep = 'N';
                            %Prompt if done with design
                            Done = questdlg('Design Complete?','Yes','No');
                            switch Done
                                case 'Yes'
                                    Itin_Select = 1;
                                case 'No'
                                    Itin_Select = 0;
                                    Current_Itin = [Current_Itin; 'O'];
                            end
                            
                            %Based on Itinerary, Pick Poincare Section and tube to integrate
                        elseif isequal(Current_Itin(end-1:end)',['O','N'])
                            app.PoincSect = 'P2';
                            if length(ItineraryRegion.Vertices) < 30 %If we have a low number of points on the Poincare Section call this to generate more
                                Points = ItineraryRegion.Vertices;
                                Points = [Points;Points(1,:)];
                                p = interparc(app,150,Points(:,1),Points(:,2),'linear');
                                ItineraryRegion = polyshape(p,'Simplify',false);
                                [x,y] = centroid(ItineraryRegion);
                                ItineraryRegion = scale(ItineraryRegion,0.98,[x,y]);
                            end
                            Tube1 = cvrt_IC(app,ItineraryRegion.Vertices,'P4');
                            Tube2 = xL2_sn;
                            Mod = 'Stable';
                            %Call function to evaluate Poincare Section
                            ItineraryRegion = PoincareEvaluate(app,Tube1,Tube2,app.PoincSect,globalVar,Mod);
                            InitialStep = 0;
                            ItineraryStep = 'N';
                            %Prompt if done with design
                            Done = questdlg('Design Complete?','Yes','No');
                            switch Done
                                case 'Yes'
                                    Itin_Select = 1;
                                case 'No'
                                    Itin_Select = 0;
                                    Current_Itin = [Current_Itin; 'O'];
                            end
                        end
                        
                end
                
                if Itin_Select == 1
                    
                    Itin_Complete = 1; %For exit condition
                    
                end
            end
            
            app.FinalRegion = ItineraryRegion;
        end
        

        function ItineraryRegion = PoincareEvaluate(app,Tube1,Tube2,PoincareSection,globalVar,Mod)
            % Inputs
            % ------
            % 1) Tube1 - Stable Manifold Tube
            % 2) Tube2 - Unstable Manifold Tube
            % 3) PoincareSection - Poincare Section to analyze
            % 4) globalVar - Global Variable
            % 5) Mod - Modifier to determine which tube to integrate
            %
            % Outputs
            % --------
            % 1) ItineraryRegion
            %
            %Tube1 is always stable, Tube2 is always unstable
            Itin_Found = 0;
            trips = 1;
            color = 'green';
            index = 1;
            ValidReg = [];
            
            %Convert Tubes to right data type if not there
            if iscell(Tube1)
                Tube1 = cell2mat(Tube1);
            elseif iscell(Tube2)
                Tube2 = cell2mat(Tube2);
            end
            
            
            while ~Itin_Found
                switch PoincareSection
                    case 'P1'
                        
                        if trips == 1
                            P1 = figure();
                            
                            if Tube1(1,1) > 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P4
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_1/4', -1);
                                    Tube1(i,:) = x(end,:);
                                end
                                
                            elseif Tube2(1,1) > 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P4
                                for i = 1:length(Tube2)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_1/4', 1);
                                    
                                    if x(end,1) < -1
                                        Tube2(i,:) = Tube2(i-1,:);
                                    else
                                        Tube2(i,:) = x(end,:);
                                    end
                                end
                            end
                            
                        end
                        %Clean data for outliers
                        Tube1 = cleandata(app,Tube1,'x',PoincareSection);
                        Tube2 = cleandata(app,Tube2,'x');
                        
                        %Create Poincare Section
                        P1_poly_1(trips) = polyshape(Tube1(:,1),Tube1(:,4));
                        P1_poly_2(trips) = polyshape(Tube2(:,1),Tube2(:,4));
                        
                        plot(app.ItinRegion,P1_poly_1);
                        hold on
                        plot(app.ItinRegion,P1_poly_2);
                        
                        %Step through each polyshape and find if it intersects the other tubes polyshape
                        if trips > 1
                            %Compare tubes with each other
                            for i = 1:trips
                                P1_s = P1_poly_1(i);
                                for j = 1:trips
                                    P1_Intersect = intersect((P1_s),P1_poly_2(j));
                                    if ~isempty(P1_Intersect.Vertices)
                                        plot(app.ItinRegion,P1_Intersect,'FaceColor',[0.4940 0.1840 0.5560])
                                        ItineraryRegion = P1_Intersect;
                                        ValidReg{index} = ItineraryRegion;
                                        index = index+1;
                                    end
                                end
                            end
                            
                        else
                            P1_Intersect = intersect(P1_poly_1(trips),P1_poly_2(trips));
                        end
                        
                        if ~isempty(ValidReg)
                            ItineraryRegion = ValidReg{1}; %Take the first region that provides the transport necessary
                            Itin_Found = 1;
                        else
                            %Integrate until the next crossing of P4
                            for i = 1:length(Tube1)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_1/4', -1);
                                Tube1(i,:) = x(end,:);
                            end
                            for i = 1:length(Tube2)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_1/4', 1);
                                Tube2(i,:) = x(end,:);
                            end
                        end
                        trips = trips + 1;
                        
                    case 'P2'
                        
                        if trips == 1
                            P2 = figure();
                            if Tube1(1,1) < 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P2
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 20],'Poincare_2/3', -1);
                                    Tube1(i,:) = x(end,:);
                                end
                                
                            elseif Tube2(1,1) < 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P2
                                for i = 1:length(Tube2)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 20],'Poincare_2/3', 1);
                                    Tube2(i,:) = x(end,:);
                                end
                            end
                            
                            if Tube1(1,2) > 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P2
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 20],'Poincare_2/3', -1);
                                    Tube1(i,:) = x(end,:);
                                    
                                end
                            elseif Tube2(1,2) > 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P2
                                for i = 1:length(Tube2)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 20],'Poincare_2/3', 1);
                                    Tube2(i,:) = x(end,:);
                                end
                            end
                            
                        end
                        
                        %Clean data of outliers
                        Tube1 = cleandata(app,Tube1,'y');
                        Tube2 = cleandata(app,Tube2,'y');
                        
                        %Create Poincare Sections
                        P2_poly_xL2 = polyshape(Tube1(:,2),Tube1(:,5));
                        P2_poly_xL1 = polyshape(Tube2(:,2),Tube2(:,5));
                        
                        plot(app.ItinRegion,P2_poly_xL1);
                        hold on
                        plot(app.ItinRegion,P2_poly_xL2);
                        
                        %Find intersection
                        P2_Intersect = intersect(P2_poly_xL1,P2_poly_xL2);
                        if ~isempty(P2_Intersect.Vertices)
                            P2_int = figure();
                            plot(app.ItinRegion,P2_Intersect,'FaceColor',[0.4940 0.1840 0.5560])
                            ItineraryRegion = P2_Intersect;
                            Itin_Found = 1;
                        else
                            %Integrate the non-itinerary region
                            if isequal(Mod,'Stable')
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_2/3', -1);
                                    Tube1(i,:) = x(end,:);
                                end
                            else
                                for i = 1:length(Tube2)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_2/3', 1);
                                    Tube2(i,:) = x(end,:);
                                end
                            end
                        end
                        trips = trips + 1;
                        
                        
                    case 'P3'
                        
                        if trips == 1
                            P3 = figure();
                            if Tube1(1,1) < 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P3
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_2/3', -1);
                                    Tube1(i,:) = x(end,:);
                                end
                                
                            elseif Tube2(1,1) < 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P3
                                for i = 1:length(Tube2)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_2/3', 1);
                                    Tube2(i,:) = x(end,:);
                                end
                            end
                            
                            
                            if Tube1(1,2) < 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P3
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_2/3', -1);
                                    Tube1(i,:) = x(end,:);
                                end
                                if Tube2(1,2) < 0 %Tells us we are not at the right poincare section
                                    %Integrate until the next crossing of P3
                                    for i = 1:length(Tube2)
                                        [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_2/3', 1);
                                        Tube2(i,:) = x(end,:);
                                    end
                                end
                            end
                        end
                        
                        %Clean data of outliers
                        Tube1 = cleandata(app,Tube1,'y');
                        Tube2 = cleandata(app,Tube2,'y');
                        
                        %Create Poincare Sections
                        P3_poly_xL1 = polyshape(Tube1(:,2),Tube1(:,5));
                        P3_poly_xL2 = polyshape(Tube2(:,2),Tube2(:,5));
                        
                        plot(app.ItinRegion,P3_poly_xL1);
                        hold on
                        plot(app.ItinRegion,P3_poly_xL2);
                        
                        %Find intersections
                        P3_Intersect = intersect(P3_poly_xL1,P3_poly_xL2);
                        if ~isempty(P3_Intersect.Vertices)
                            P3_int = figure();
                            plot(app.ItinRegion,P3_Intersect,'FaceColor',[0.4940 0.1840 0.5560])
                            ItineraryRegion = P3_Intersect;
                            Itin_Found = 1;
                        else
                            %Integrate the non-itinerary region
                            if isequal(Mod,'Stable')
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_2/3', -1);
                                    Tube1(i,:) = x(end,:);
                                end
                            else
                                for i = 1:length(Tube2)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_2/3', 1);
                                    Tube2(i,:) = x(end,:);
                                end
                            end
                        end
                        trips = trips + 1;
                        
                    case 'P4'
                        
                        if trips == 1
                            P4 = figure();
                            
                            if Tube1(1,1) > 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P4
                                for i = 1:length(Tube1)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_1/4', -1);
                                    Tube1(i,:) = x(end,:);
                                end
                                
                            elseif Tube2(1,1) > 0 %Tells us we are not at the right poincare section
                                %Integrate until the next crossing of P4
                                for i = 1:length(Tube2)
                                    [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_1/4', 1);
                                    Tube2(i,:) = x(end,:);
                                end
                            end
                            
                        end
                        %Clean data of outliers
                        Tube1 = cleandata(app,Tube1,'x',PoincareSection);
                        Tube2 = cleandata(app,Tube2,'x');
                        
                        %Create Poincare Sections
                        P4_poly_1(trips) = polyshape(Tube1(:,1),Tube1(:,4));
                        P4_poly_2(trips) = polyshape(Tube2(:,1),Tube2(:,4));
                        
                        
                        plot(app.ItinRegion,P4_poly_1(trips));
                        hold on
                        plot(app.ItinRegion,P4_poly_2(trips));
                        
                        %Step through each polyshape and find if it intersects the
                        %other tubes polyshape
                        if trips > 1
                            %Compare tubes with each other
                            for i = 1:trips
                                P4_s = P4_poly_1(trips);
                                for j = 1:trips
                                    P4_Intersect = intersect((P4_s),P4_poly_2(trips));
                                    if ~isempty(P4_Intersect.Vertices)
                                        plot(app.ItinRegion,P4_Intersect,'FaceColor',[0.4940 0.1840 0.5560])
                                        ItineraryRegion = P4_Intersect; 
                                        ValidReg{index} = ItineraryRegion;
                                        index = index+1;
                                    end
                                end
                            end
                            
                        else
                            P4_Intersect = intersect(P4_poly_1(trips),P4_poly_2(trips));
                        end
                        
                        if ~isempty(ValidReg)
                            plot(app.ItinRegion,P4_Intersect,'FaceColor',[0.4940 0.1840 0.5560])
                            ItineraryRegion = P4_Intersect; %Need to add function to interpolate additional points to better define the region
                            ItineraryRegion = ValidReg{1}; %Take the first region that provides the transport necessary
                            Itin_Found = 1;
                        else
                            %Integrate until the next crossing of P4
                            for i = 1:length(Tube1)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube1(i,1:6),[0 15],'Poincare_1/4', -1);
                                Tube1(i,:) = x(end,:);
                            end
                            for i = 1:length(Tube2)
                                [t,x] = integrate(app,globalVar,globalVar.functions.systemDynamics,Tube2(i,1:6),[0 15],'Poincare_1/4', 1);
                                Tube2(i,:) = x(end,:);
                            end
                        end
                        trips = trips + 1;
                end
            end
            %Assign output
            app.FinalRegion = ItineraryRegion;
        end
        
        function [pt,dudt,fofthandle] = interparc(app,t,px,py,varargin)
            % interparc: interpolate points along a curve in 2 or more dimensions
            % usage: pt = interparc(t,px,py)    % a 2-d curve
            % usage: pt = interparc(t,px,py,pz) % a 3-d curve
            % usage: pt = interparc(t,px,py,pz,pw,...) % a 4-d or higher dimensional curve
            % usage: pt = interparc(t,px,py,method) % a 2-d curve, method is specified
            % usage: [pt,dudt,fofthandle] = interparc(t,px,py,...) % also returns derivatives, and a function handle
            %
            % Interpolates new points at any fractional point along
            % the curve defined by a list of points in 2 or more
            % dimensions. The curve may be defined by any sequence
            % of non-replicated points.
            %
            % arguments: (input)
            %  t   - vector of numbers, 0 <= t <= 1, that define
            %        the fractional distance along the curve to
            %        interpolate the curve at. t = 0 will generate
            %        the very first point in the point list, and
            %        t = 1 yields the last point in that list.
            %        Similarly, t = 0.5 will yield the mid-point
            %        on the curve in terms of arc length as the
            %        curve is interpolated by a parametric spline.
            %
            %        If t is a scalar integer, at least 2, then
            %        it specifies the number of equally spaced
            %        points in arclength to be generated along
            %        the curve.
            %
            %  px, py, pz, ... - vectors of length n, defining
            %        points along the curve. n must be at least 2.
            %        Exact Replicate points should not be present
            %        in the curve, although there is no constraint
            %        that the curve has replicate independent
            %        variables.
            %
            %  method - (OPTIONAL) string flag - denotes the method
            %        used to compute the points along the curve.
            %
            %        method may be any of 'linear', 'spline', or 'pchip',
            %        or any simple contraction thereof, such as 'lin',
            %        'sp', or even 'p'.
            %
            %        method == 'linear' --> Uses a linear chordal
            %               approximation to interpolate the curve.
            %               This method is the most efficient.
            %
            %        method == 'pchip' --> Uses a parametric pchip
            %               approximation for the interpolation
            %               in arc length.
            %
            %        method == 'spline' --> Uses a parametric spline
            %               approximation for the interpolation in
            %               arc length. Generally for a smooth curve,
            %               this method may be most accurate.
            %
            %        method = 'csape' --> if available, this tool will
            %               allow a periodic spline fit for closed curves.
            %               ONLY use this method if your points should
            %               represent a closed curve.
            %
            %               If the last point is NOT the same as the
            %               first point on the curve, then the curve
            %               will be forced to be periodic by this option.
            %               That is, the first point will be replicated
            %               onto the end.
            %
            %               If csape is not present in your matlab release,
            %               then an error will result.
            %
            %        DEFAULT: 'spline'
            %
            %
            % arguments: (output)
            %  pt - Interpolated points at the specified fractional
            %        distance (in arc length) along the curve.
            %
            %  dudt - when a second return argument is required,
            %       interparc will return the parametric derivatives
            %       (dx/dt, dy/dt, dz/dt, ...) as an array.
            %
            %  fofthandle - a function handle, taking numbers in the interval [0,1]
            %       and evaluating the function at those points.
            %
            %       Extrapolation will not be permitted by this call.
            %       Any values of t that lie outside of the interval [0,1]
            %       will be clipped to the endpoints of the curve.
            %
            % Example:
            % % Interpolate a set of unequally spaced points around
            % % the perimeter of a unit circle, generating equally
            % % spaced points around the perimeter.
            % theta = sort(rand(15,1))*2*pi;
            % theta(end+1) = theta(1);
            % px = cos(theta);
            % py = sin(theta);
            %
            % % interpolate using parametric splines
            % pt = interparc(100,px,py,'spline');
            %
            % % Plot the result
            % plot(px,py,'r*',pt(:,1),pt(:,2),'b-o')
            % axis([-1.1 1.1 -1.1 1.1])
            % axis equal
            % grid on
            % xlabel X
            % ylabel Y
            % title 'Points in blue are uniform in arclength around the circle'
            %
            %
            % Example:
            % % For the previous set of points, generate exactly 6
            % % points around the parametric splines, verifying
            % % the uniformity of the arc length interpolant.
            % pt = interparc(6,px,py,'spline');
            %
            % % Convert back to polar form. See that the radius
            % % is indeed 1, quite accurately.
            % [TH,R] = cart2pol(pt(:,1),pt(:,2))
            % % TH =
            % %       0.86005
            % %        2.1141
            % %       -2.9117
            % %        -1.654
            % %      -0.39649
            % %       0.86005
            % % R =
            % %             1
            % %        0.9997
            % %        0.9998
            % %       0.99999
            % %        1.0001
            % %             1
            %
            % % Unwrap the polar angles, and difference them.
            % diff(unwrap(TH))
            % % ans =
            % %        1.2541
            % %        1.2573
            % %        1.2577
            % %        1.2575
            % %        1.2565
            %
            % % Six points around the circle should be separated by
            % % 2*pi/5 radians, if they were perfectly uniform. The
            % % slight differences are due to the imperfect accuracy
            % % of the parametric splines.
            % 2*pi/5
            % % ans =
            % %        1.2566
            %
            %
            % See also: arclength, spline, pchip, interp1
            %
            % Author: John D'Errico
            % e-mail: woodchips@rochester.rr.com
            % Release: 1.0
            % Release date: 3/15/2010
            % unpack the arguments and check for errors
            if nargin < 3
                error('ARCLENGTH:insufficientarguments', ...
                    'at least t, px, and py must be supplied')
            end
            t = t(:);
            if (numel(t) == 1) && (t > 1) && (rem(t,1) == 0)
                % t specifies the number of points to be generated
                % equally spaced in arclength
                t = linspace(0,1,t)';
            elseif any(t < 0) || any(t > 1)
                error('ARCLENGTH:impropert', ...
                    'All elements of t must be 0 <= t <= 1')
            end
            % how many points will be interpolated?
            nt = numel(t);
            % the number of points on the curve itself
            px = px(:);
            py = py(:);
            n = numel(px);
            % are px and py both vectors of the same length?
            if ~isvector(px) || ~isvector(py) || (length(py) ~= n)
                error('ARCLENGTH:improperpxorpy', ...
                    'px and py must be vectors of the same length')
            elseif n < 2
                error('ARCLENGTH:improperpxorpy', ...
                    'px and py must be vectors of length at least 2')
            end
            % compose px and py into a single array. this way,
            % if more dimensions are provided, the extension
            % is trivial.
            pxy = [px,py];
            ndim = 2;
            % the default method is 'linear'
            method = 'spline';
            % are there any other arguments?
            if nargin > 3
                % there are. check the last argument. Is it a string?
                if ischar(varargin{end})
                    method = varargin{end};
                    varargin(end) = [];
                    
                    % method may be any of {'linear', 'pchip', 'spline', 'csape'.}
                    % any any simple contraction thereof.
                    valid = {'linear', 'pchip', 'spline', 'csape'};
                    [method,errstr] = validstring(app,method,valid);
                    if ~isempty(errstr)
                        error('INTERPARC:incorrectmethod',errstr)
                    end
                end
                
                % anything that remains in varargin must add
                % an additional dimension on the curve/polygon
                for i = 1:numel(varargin)
                    pz = varargin{i};
                    pz = pz(:);
                    if numel(pz) ~= n
                        error('ARCLENGTH:improperpxorpy', ...
                            'pz must be of the same size as px and py')
                    end
                    pxy = [pxy,pz]; %#ok
                end
                
                % the final number of dimensions provided
                ndim = size(pxy,2);
            end
            % if csape, then make sure the first point is replicated at the end.
            % also test to see if csape is available
            if method(1) == 'c'
                if exist('csape','file') == 0
                    error('CSAPE was requested, but you lack the necessary toolbox.')
                end
                
                p1 = pxy(1,:);
                pend = pxy(end,:);
                
                % get a tolerance on whether the first point is replicated.
                if norm(p1 - pend) > 10*eps(norm(max(abs(pxy),[],1)))
                    % the two end points were not identical, so wrap the curve
                    pxy(end+1,:) = p1;
                    nt = nt + 1;
                end
            end
            % preallocate the result, pt
            pt = NaN(nt,ndim);
            % Compute the chordal (linear) arclength
            % of each segment. This will be needed for
            % any of the methods.
            chordlen = sqrt(sum(diff(pxy,[],1).^2,2));
            % Normalize the arclengths to a unit total
            chordlen = chordlen/sum(chordlen);
            % cumulative arclength
            cumarc = [0;cumsum(chordlen)];
            % The linear interpolant is trivial. do it as a special case
            if method(1) == 'l'
                % The linear method.
                
                % which interval did each point fall in, in
                % terms of t?
                [junk,tbins] = histc(t,cumarc); %#ok
                
                % catch any problems at the ends
                tbins((tbins <= 0) | (t <= 0)) = 1;
                tbins((tbins >= n) | (t >= 1)) = n - 1;
                
                % interpolate
                s = (t - cumarc(tbins))./chordlen(tbins);
                % be nice, and allow the code to work on older releases
                % that don't have bsxfun
                pt = pxy(tbins,:) + (pxy(tbins+1,:) - pxy(tbins,:)).*repmat(s,1,ndim);
                
                % do we need to compute derivatives here?
                if nargout > 1
                    dudt = (pxy(tbins+1,:) - pxy(tbins,:))./repmat(chordlen(tbins),1,ndim);
                end
                
                % do we need to create the spline as a piecewise linear function?
                if nargout > 2
                    spl = cell(1,ndim);
                    for i = 1:ndim
                        coefs = [diff(pxy(:,i))./diff(cumarc),pxy(1:(end-1),i)];
                        spl{i} = mkpp(cumarc.',coefs);
                    end
                    
                    %create a function handle for evaluation, passing in the splines
                    fofthandle = @(t) foft(t,spl);
                end
                
                % we are done at this point
                return
            end
            % If we drop down to here, we have either a spline
            % or csape or pchip interpolant to work with.
            % compute parametric splines
            spl = cell(1,ndim);
            spld = spl;
            diffarray = [3 0 0;0 2 0;0 0 1;0 0 0];
            for i = 1:ndim
                switch method
                    case 'pchip'
                        spl{i} = pchip(cumarc,pxy(:,i));
                    case 'spline'
                        spl{i} = spline(cumarc,pxy(:,i));
                        nc = numel(spl{i}.coefs);
                        if nc < 4
                            % just pretend it has cubic segments
                            spl{i}.coefs = [zeros(1,4-nc),spl{i}.coefs];
                            spl{i}.order = 4;
                        end
                    case 'csape'
                        % csape was specified, so the curve is presumed closed,
                        % therefore periodic
                        spl{i} = csape(cumarc,pxy(:,i),'periodic');
                        nc = numel(spl{i}.coefs);
                        if nc < 4
                            % just pretend it has cubic segments
                            spl{i}.coefs = [zeros(1,4-nc),spl{i}.coefs];
                            spl{i}.order = 4;
                        end
                end
                
                % and now differentiate them
                xp = spl{i};
                xp.coefs = xp.coefs*diffarray;
                xp.order = 3;
                spld{i} = xp;
            end
            % catch the case where there were exactly three points
            % in the curve, and spline was used to generate the
            % interpolant. In this case, spline creates a curve with
            % only one piece, not two.
            if (numel(cumarc) == 3) && (method(1) == 's')
                cumarc = spl{1}.breaks;
                n = numel(cumarc);
                chordlen = sum(chordlen);
            end
            % Generate the total arclength along the curve
            % by integrating each segment and summing the
            % results. The integration scheme does its job
            % using an ode solver.
            % polyarray here contains the derivative polynomials
            % for each spline in a given segment
            polyarray = zeros(ndim,3);
            seglen = zeros(n-1,1);
            % options for ode45
            opts = odeset('reltol',1.e-9);
            for i = 1:spl{1}.pieces
                % extract polynomials for the derivatives
                for j = 1:ndim
                    polyarray(j,:) = spld{j}.coefs(i,:);
                end
                
                % integrate the arclength for the i'th segment
                % using ode45 for the integral. I could have
                % done this part with quad too, but then it
                % would not have been perfectly (numerically)
                % consistent with the next operation in this tool.
                [tout,yout] = ode45(@(t,y) segkernel(t,y),[0,chordlen(i)],0,opts); %#ok
                seglen(i) = yout(end);
            end
            % and normalize the segments to have unit total length
            totalsplinelength = sum(seglen);
            cumseglen = [0;cumsum(seglen)];
            % which interval did each point fall into, in
            % terms of t, but relative to the cumulative
            % arc lengths along the parametric spline?
            [junk,tbins] = histc(t*totalsplinelength,cumseglen); %#ok
            % catch any problems at the ends
            tbins((tbins <= 0) | (t <= 0)) = 1;
            tbins((tbins >= n) | (t >= 1)) = n - 1;
            % Do the fractional integration within each segment
            % for the interpolated points. t is the parameter
            % used to define the splines. It is defined in terms
            % of a linear chordal arclength. This works nicely when
            % a linear piecewise interpolant was used. However,
            % what is asked for is an arclength interpolation
            % in terms of arclength of the spline itself. Call s
            % the arclength traveled along the spline.
            s = totalsplinelength*t;
            % the ode45 options will now include an events property
            % so we can catch zero crossings.
            opts = odeset('reltol',1.e-9,'events',@ode_events);
            ti = t;
            for i = 1:nt
                % si is the piece of arc length that we will look
                % for in this spline segment.
                si = s(i) - cumseglen(tbins(i));
                
                % extract polynomials for the derivatives
                % in the interval the point lies in
                for j = 1:ndim
                    polyarray(j,:) = spld{j}.coefs(tbins(i),:);
                end
                
                % we need to integrate in t, until the integral
                % crosses the specified value of si. Because we
                % have defined totalsplinelength, the lengths will
                % be normalized at this point to a unit length.
                %
                % Start the ode solver at -si, so we will just
                % look for an event where y crosses zero.
                [tout,yout,te,ye] = ode45(@(t,y) segkernel(t,y),[0,chordlen(tbins(i))],-si,opts); %#ok
                
                % we only need that point where a zero crossing occurred
                % if no crossing was found, then we can look at each end.
                if ~isempty(te)
                    ti(i) = te(1) + cumarc(tbins(i));
                else
                    % a crossing must have happened at the very
                    % beginning or the end, and the ode solver
                    % missed it, not trapping that event.
                    if abs(yout(1)) < abs(yout(end))
                        % the event must have been at the start.
                        ti(i) = tout(1) + cumarc(tbins(i));
                    else
                        % the event must have been at the end.
                        ti(i) = tout(end) + cumarc(tbins(i));
                    end
                end
            end
            % Interpolate the parametric splines at ti to get
            % our interpolated value.
            for L = 1:ndim
                pt(:,L) = ppval(spl{L},ti);
            end
            % do we need to compute first derivatives here at each point?
            if nargout > 1
                dudt = zeros(nt,ndim);
                for L = 1:ndim
                    dudt(:,L) = ppval(spld{L},ti);
                end
            end
            % create a function handle for evaluation, passing in the splines
            if nargout > 2
                fofthandle = @(t) foft(t,spl);
            end
            % ===============================================
            %  nested function for the integration kernel
            % ===============================================
            function val = segkernel(t,y) %#ok
                % sqrt((dx/dt)^2 + (dy/dt)^2 + ...)
                val = zeros(size(t));
                for k = 1:ndim
                    val = val + polyval(polyarray(k,:),t).^2;
                end
                val = sqrt(val);
                
            end % function segkernel
            % ===============================================
            %  nested function for ode45 integration events
            % ===============================================
            function [value,isterminal,direction] = ode_events(t,y) %#ok
                % ode event trap, looking for zero crossings of y.
                value = y;
                isterminal = ones(size(y));
                direction = ones(size(y));
            end % function ode_events
        end % mainline - interparc
        % ===============================================
        %       end mainline - interparc
        % ===============================================
        %       begin subfunctions
        % ===============================================
        % ===============================================
        %  subfunction for evaluation at any point externally
        % ===============================================
        function f_t = foft(t,spl)
            % tool allowing the user to evaluate the interpolant at any given point for any values t in [0,1]
            pdim = numel(spl);
            f_t = zeros(numel(t),pdim);
            % convert t to a column vector, clipping it to [0,1] as we do.
            t = max(0,min(1,t(:)));
            % just loop over the splines in the cell array of splines
            for i = 1:pdim
                f_t(:,i) = ppval(spl{i},t);
            end
        end % function foft
        function [str,errorclass] = validstring(app,arg,valid)
            % validstring: compares a string against a set of valid options
            % usage: [str,errorclass] = validstring(arg,valid)
            %
            % If a direct hit, or any unambiguous shortening is found, that
            % string is returned. Capitalization is ignored.
            %
            % arguments: (input)
            %  arg - character string, to be tested against a list
            %        of valid choices. Capitalization is ignored.
            %
            %  valid - cellstring array of alternative choices
            %
            % Arguments: (output)
            %  str - string - resulting choice resolved from the
            %        list of valid arguments. If no unambiguous
            %        choice can be resolved, then str will be empty.
            %
            %  errorclass - string - A string argument that explains
            %        the error. It will be one of the following
            %        possibilities:
            %
            %        ''  --> No error. An unambiguous match for arg
            %                was found among the choices.
            %
            %        'No match found' --> No match was found among
            %                the choices provided in valid.
            %
            %        'Ambiguous argument' --> At least two ambiguous
            %                matches were found among those provided
            %                in valid.
            %
            %
            % Example:
            %  valid = {'off' 'on' 'The sky is falling'}
            %
            %
            % See also: parse_pv_pairs, strmatch, strcmpi
            %
            % Author: John D'Errico
            % e-mail: woodchips@rochester.rr.com
            % Release: 1.0
            % Release date: 3/25/2010
            ind = find(strncmpi(lower(arg),valid,numel(arg)));
            if isempty(ind)
                % No hit found
                errorclass = 'No match found';
                str = '';
            elseif (length(ind) > 1)
                % Ambiguous arg, hitting more than one of the valid options
                errorclass = 'Ambiguous argument';
                str = '';
                return
            else
                errorclass = '';
                str = valid{ind};
            end
        end % function validstring
        
        function IC_ret = cvrt_IC(app,Ic_in,PoincareSection)
            
            mu1 = 1-app.mu;
            mu2 = app.mu;
            
            for i = 1:size(Ic_in,1)
                Ic = Ic_in(i,:);
                if isequal(PoincareSection,'P4')
                    IC = [Ic(1),0,Ic(2),0];
                    %Comput vy using 4.4.4
                    r1 = sqrt((IC(1)+mu2)^2 + IC(2)^2);
                    r2 = sqrt((IC(1)-mu1)^2 + IC(2)^2);
                    U_Bar = (-1/2)*(IC(1)^2 + IC(2)^2) - (mu1/r1) - (mu2/r2) - (1/2)*(mu1*mu2); %Defined by 2.3.9
                    vy = sqrt((IC(3))^2 - 2*U_Bar + 2*app.E);
                    
                    IC = [IC(1),IC(2),0,IC(3),vy,0];
                elseif isequal(PoincareSection,'P1')
                    IC = [Ic(1),0,Ic(2),0];
                    %Comput vy using 4.4.4
                    r1 = sqrt((IC(1)+mu2)^2 + IC(2)^2);
                    r2 = sqrt((IC(1)-mu1)^2 + IC(2)^2);
                    U_Bar = (-1/2)*(IC(1)^2 + IC(2)^2) - (mu1/r1) - (mu2/r2) - (1/2)*(mu1*mu2); %Defined by 2.3.9
                    vy = -sqrt((IC(3))^2 - 2*U_Bar + 2*app.E);
                    
                    IC = [IC(1),IC(2),0,IC(3),vy,0];
                    
                    
                elseif isequal(PoincareSection,'P3')
                    IC = [mu1,Ic(1),0,Ic(2)];
                    %Comput vx using 4.4.4
                    r1 = sqrt((IC(1)+mu2)^2 + IC(2)^2);
                    r2 = sqrt((IC(1)-mu1)^2 + IC(2)^2);
                    U_Bar = (-1/2)*(IC(1)^2 + IC(2)^2) - (mu1/r1) - (mu2/r2) - (1/2)*(mu1*mu2); %Defined by 2.3.9
                    vx = -sqrt(-(IC(4))^2 - 2*U_Bar + 2*app.E);
                    
                    IC = [IC(1),IC(2),0,vx,IC(4),0];
                else
                    IC = [mu1,Ic(1),0,Ic(2)];
                    %Comput vx using 4.4.4
                    r1 = sqrt((IC(1)+mu2)^2 + IC(2)^2);
                    r2 = sqrt((IC(1)-mu1)^2 + IC(2)^2);
                    U_Bar = (-1/2)*(IC(1)^2 + IC(2)^2) - (mu1/r1) - (mu2/r2) - (1/2)*(mu1*mu2); %Defined by 2.3.9
                    vx = sqrt(-(IC(4))^2 - 2*U_Bar + 2*app.E);
                    
                    IC = [IC(1),IC(2),0,vx,IC(4),0];
                end
                IC_ret(i,:) = IC;
            end
            
        end
        
        function [Poinc_Plot,Poinc_return] = cleandata(app,Poinc_Plot,x_or_y,PoincareSection)
            % Inputs
            % ------
            % 1) Poinc_Plot - Points on Poincare Section
            % 2) PoincareSection - Poincare Section points are on (not currently used)
            % 3) x_or_y - Cleaning Data on U2/U3 or U1/U4
            %
            % Outputs
            % --------
            % 1) Poinc_Plot - Points on Poincare Section
            % 2)Poinc_return - Cleaned Points
            %
            index = 1;
            Bad_Index = [];
            if x_or_y == 'x'
                %Compute the mean of data in the plane
                meanx = mean(Poinc_Plot(:,1));
                meanx_dot = mean(Poinc_Plot(:,4));
                std_dev_x = std(Poinc_Plot(:,1));
                std_dev_x_dot = std(Poinc_Plot(:,4));
                for j = 1:length(Poinc_Plot(:,1))
                    %Get distance of point from the mean location
                    r_x = meanx-Poinc_Plot(j,1);
                    r_x_dot = meanx_dot-Poinc_Plot(j,4);
                    if abs(r_x) > 1.5*std_dev_x || abs(r_x_dot) > 1.5*std_dev_x_dot
                        Bad_Index(index) = j;
                        index = index + 1;
                    end
                    if sum([Poinc_Plot(j,1), Poinc_Plot(j,4)]) == 0
                        Bad_Index(index) = j;
                        index = index + 1;
                    end
                end
                for j = 1:length(Bad_Index)
                    Poinc_Plot(Bad_Index(j),:) = [];
                    Bad_Index = Bad_Index - 1; %Adjust for removed point
                end
            else
                %Compute the mean of data in the plane
                meany = mean(Poinc_Plot(:,2));
                meany_dot = mean(Poinc_Plot(:,5));
                std_dev_y = std(Poinc_Plot(:,2));
                std_dev_y_dot = std(Poinc_Plot(:,5));
                for j = 1:length(Poinc_Plot(:,2))
                    %Get distance of point from the mean location
                    r_y = meany-Poinc_Plot(j,2);
                    r_y_dot = meany_dot-Poinc_Plot(j,5);
                    if  abs(Poinc_Plot(j,2)) > 0.5 || abs(Poinc_Plot(j,5)) > 5 || abs(Poinc_Plot(j,1) - (1-app.mu)) > 0.1
                        Bad_Index(index) = j;
                        index = index + 1;
                    end
                    if sum([Poinc_Plot(j,2), Poinc_Plot(j,5)]) == 0
                        Bad_Index(index) = j;
                        index = index + 1;
                    end
                end
                if ~isempty(Bad_Index)
                    for j = 1:length(Bad_Index)
                        Poinc_Plot(Bad_Index(j),:) = [];
                        Bad_Index = Bad_Index - 1; %Adjust for removed point
                    end
                end
            end
            
            Poinc_return = Poinc_Plot;%Return full array
            
        end
        
        function A=RemoveInfinitesimals(app,A)
            
            % Remove all entries with absolute value smaller than TOL
            TOL=1.e-14;
            
            for k=1:length(A)
               	if abs(real(A(k))) < TOL
                  		A(k)=A(k) - real(A(k));
               	end
               	if abs(imag(A(k))) < TOL
                  		A(k)=A(k) - 1i*imag(A(k));
               	end
            end
            
        end
        
        
        function varEq_initVal = varEq_initialization(app,x0)
            N=length(x0);
            Phi_t0_t0 = reshape(eye(N),N^2,1);
            
            varEq_initVal(N^2+1:N^2+N) = x0;
            
            varEq_initVal(1:N^2) = Phi_t0_t0;
            
        end
        
        function Slices = ManifoldSlice(app,Tube)
            index = 0;
            %Loop through each integrated trajectory and find intersection with specified line from (0,0) at some angle theta
            for theta = 75:5:175
                index = index + 1;
                linex = [0 10*cosd(theta)]';
                liney = [0 10*sind(theta)]';
                for j = 1:length(Tube)
                    [x0,y0] = intersections(app,Tube{j}(:,1),Tube{j}(:,2),linex,liney);
                    Slices{index}(j,1) = x0;
                    Slices{index}(j,2) = y0;
                end
            end
        end
        
        function [x0,y0,iout,jout] = intersections(app,x1,y1,x2,y2,robust)
            %INTERSECTIONS Intersections of curves.
            %   Computes the (x,y) locations where two curves intersect.  The curves
            %   can be broken with NaNs or have vertical segments.
            %
            % Example:
            %   [X0,Y0] = intersections(X1,Y1,X2,Y2,ROBUST);
            %
            % where X1 and Y1 are equal-length vectors of at least two points and represent
            % curve 1.  Similarly, X2 and Y2 represent curve 2.  X0 and Y0 are column
            % vectors containing the points at which the two curves intersect.
            %
            % ROBUST (optional) set to 1 or true means to use a slight variation of the
            % algorithm that might return duplicates of some intersection points, and then
            % remove those duplicates.  The default is true, but since the algorithm is
            % slightly slower you can set it to false if you know that your curves don't
            % intersect at any segment boundaries.  Also, the robust version properly
            % handles parallel and overlapping segments.
            %
            % The algorithm can return two additional vectors that indicate which segment
            % pairs contain intersections and where they are:
            %
            %   [X0,Y0,I,J] = intersections(X1,Y1,X2,Y2,ROBUST);
            %
            % For each element of the vector I, I(k) = (segment number of (X1,Y1)) + (how
            % far along this segment the intersection is).  For example, if I(k) = 45.25
            % then the intersection lies a quarter of the way between the line segment
            % connecting (X1(45),Y1(45)) and (X1(46),Y1(46)).  Similarly for the vector J
            % and the segments in (X2,Y2).
            %
            % You can also get intersections of a curve with itself.  Simply pass in only
            % one curve, i.e.,
            %
            %   [X0,Y0] = intersections(X1,Y1,ROBUST);
            %
            % where, as before, ROBUST is optional.
            % Version: 3.0, 30 May 2024
            % Author:  Douglas M. Schwarz
            % Email:   dmschwarz=ieee*org, dmschwarz=urgrad*rochester*edu
            % Real_email = regexprep(Email,{'=','*'},{'@','.'})
            % Theory of operation:
            %
            % Given two line segments, L1 and L2,
            %
            %   L1 endpoints:  (x1(1),y1(1)) and (x1(2),y1(2))
            %   L2 endpoints:  (x2(1),y2(1)) and (x2(2),y2(2))
            %
            % we can write four equations with four unknowns and then solve them.  The four
            % unknowns are t1, t2, x0 and y0, where (x0,y0) is the intersection of L1 and
            % L2, t1 is the distance from the starting point of L1 to the intersection
            % relative to the length of L1 and t2 is the distance from the starting point of
            % L2 to the intersection relative to the length of L2.
            %
            % So, the four equations are
            %
            %    (x1(2) - x1(1))*t1 = x0 - x1(1)
            %    (x2(2) - x2(1))*t2 = x0 - x2(1)
            %    (y1(2) - y1(1))*t1 = y0 - y1(1)
            %    (y2(2) - y2(1))*t2 = y0 - y2(1)
            %
            % Rearranging and writing in matrix form,
            %
            %  [x1(2)-x1(1)       0       -1   0;      [t1;      [-x1(1);
            %        0       x2(2)-x2(1)  -1   0;   *   t2;   =   -x2(1);
            %   y1(2)-y1(1)       0        0  -1;       x0;       -y1(1);
            %        0       y2(2)-y2(1)   0  -1]       y0]       -y2(1)]
            %
            % Let's call that A*T = B.  We can solve for T with T = A\B.
            %
            % Once we have our solution we just have to look at t1 and t2 to determine
            % whether L1 and L2 intersect.  If 0 <= t1 < 1 and 0 <= t2 < 1 then the two line
            % segments cross and we can include (x0,y0) in the output.
            %
            % In principle, we have to perform this computation on every pair of line
            % segments in the input data.  This can be quite a large number of pairs so we
            % will reduce it by doing a simple preliminary check to eliminate line segment
            % pairs that could not possibly cross.  The check is to look at the smallest
            % enclosing rectangles (with sides parallel to the axes) for each line segment
            % pair and see if they overlap.  If they do then we have to compute t1 and t2
            % (via the A\B computation) to see if the line segments cross, but if they don't
            % then the line segments cannot cross.  In a typical application, this technique
            % will eliminate most of the potential line segment pairs.
            % Input checks.
            
            if verLessThan('matlab','7.13')
               	error(nargchk(2,5,nargin))
            else
               	narginchk(2,5)
            end
            % Adjustments based on number of arguments.
            switch nargin
               	case 3
                  		robust = true;
                  		x2 = x1;
                  		y2 = y1;
                  		self_intersect = true;
               	case 4
                  		robust = x2;
                  		x2 = x1;
                  		y2 = y1;
                  		self_intersect = true;
               	case 5
                  		robust = true;
                  		self_intersect = false;
               	case 6
                  		self_intersect = false;
            end
            % x1 and y1 must be vectors with same number of points (at least 2).
            if sum(size(x1) > 1) ~= 1 || sum(size(y1) > 1) ~= 1 || ...
                  		length(x1) ~= length(y1)
               	error('X1 and Y1 must be equal-length vectors of at least 2 points.')
            end
            % x2 and y2 must be vectors with same number of points (at least 2).
            if sum(size(x2) > 1) ~= 1 || sum(size(y2) > 1) ~= 1 || ...
                  		length(x2) ~= length(y2)
               	error('X2 and Y2 must be equal-length vectors of at least 2 points.')
            end
            % Force all inputs to be column vectors.
            x1 = x1(:);
            y1 = y1(:);
            x2 = x2(:);
            y2 = y2(:);
            % Compute number of line segments in each curve and some differences we'll need
            % later.
            n1 = length(x1) - 1;
            n2 = length(x2) - 1;
            xy1 = [x1 y1];
            xy2 = [x2 y2];
            dxy1 = diff(xy1);
            dxy2 = diff(xy2);
            % Determine the combinations of i and j where the rectangle enclosing the i'th
            % line segment of curve 1 overlaps with the rectangle enclosing the j'th line
            % segment of curve 2.
            % Original method that works in old MATLAB versions, but is slower than using
            % binary singleton expansion (explicit or implicit).
            % [i,j] = find( ...
            % 	repmat(mvmin(x1),1,n2) <= repmat(mvmax(x2).',n1,1) & ...
            % 	repmat(mvmax(x1),1,n2) >= repmat(mvmin(x2).',n1,1) & ...
            % 	repmat(mvmin(y1),1,n2) <= repmat(mvmax(y2).',n1,1) & ...
            % 	repmat(mvmax(y1),1,n2) >= repmat(mvmin(y2).',n1,1));
            % Select an algorithm based on MATLAB version and number of line segments in
            % each curve.  We want to avoid forming large matrices for large numbers of line
            % segments.  If the matrices are not too large, choose the best method available
            % for the MATLAB version.
            if n1 > 1000 || n2 > 1000 || verLessThan('matlab','7.4')
               	% Determine which curve has the most line segments.
               	if n1 >= n2
                  		% Curve 1 has more segments, loop over segments of curve 2.
                  		ijc = cell(1,n2);
                  		min_x1 = mvmin(x1);
                  		max_x1 = mvmax(x1);
                  		min_y1 = mvmin(y1);
                  		max_y1 = mvmax(y1);
                  		for k = 1:n2
                     			k1 = k + 1;
                     			ijc{k} = find( ...
                        				min_x1 <= max(x2(k),x2(k1)) & max_x1 >= min(x2(k),x2(k1)) & ...
                        				min_y1 <= max(y2(k),y2(k1)) & max_y1 >= min(y2(k),y2(k1)));
                     			ijc{k}(:,2) = k;
                  		end
                  		ij = vertcat(ijc{:});
                  		i = ij(:,1);
                  		j = ij(:,2);
               	else
                  		% Curve 2 has more segments, loop over segments of curve 1.
                  		ijc = cell(1,n1);
                  		min_x2 = mvmin(x2);
                  		max_x2 = mvmax(x2);
                  		min_y2 = mvmin(y2);
                  		max_y2 = mvmax(y2);
                  		for k = 1:n1
                     			k1 = k + 1;
                     			ijc{k}(:,2) = find( ...
                        				min_x2 <= max(x1(k),x1(k1)) & max_x2 >= min(x1(k),x1(k1)) & ...
                        				min_y2 <= max(y1(k),y1(k1)) & max_y2 >= min(y1(k),y1(k1)));
                     			ijc{k}(:,1) = k;
                  		end
                  		ij = vertcat(ijc{:});
                  		i = ij(:,1);
                  		j = ij(:,2);
               	end
               	
            elseif verLessThan('matlab','9.1')
               	% Use bsxfun.
               	[i,j] = find( ...
                  		bsxfun(@le,mvmin(x1),mvmax(x2).') & ...
                  		bsxfun(@ge,mvmax(x1),mvmin(x2).') & ...
                  		bsxfun(@le,mvmin(y1),mvmax(y2).') & ...
                  		bsxfun(@ge,mvmax(y1),mvmin(y2).'));
               	
            else
               	% Use implicit expansion.
               	[i,j] = find( ...
                  		mvmin(x1) <= mvmax(x2).' & mvmax(x1) >= mvmin(x2).' & ...
                  		mvmin(y1) <= mvmax(y2).' & mvmax(y1) >= mvmin(y2).');
               	
            end
            % Find segments pairs which have at least one vertex = NaN and remove them.
            % This line is a fast way of finding such segment pairs.  We take advantage of
            % the fact that NaNs propagate through calculations, in particular subtraction
            % (in the calculation of dxy1 and dxy2, which we need anyway) and addition.  At
            % the same time we can remove redundant combinations of i and j in the case of
            % finding intersections of a line with itself.
            if self_intersect
               	remove = isnan(sum(dxy1(i,:) + dxy2(j,:),2)) | j <= i + 1;
            else
               	remove = isnan(sum(dxy1(i,:) + dxy2(j,:),2));
            end
            i(remove) = [];
            j(remove) = [];
            % Initialize matrices.  We'll put the T's and B's in matrices and use them one
            % column at a time.  AA is a 3-D extension of A where we'll use one plane at a
            % time.
            n = length(i);
            T = zeros(4,n);
            AA = zeros(4,4,n);
            AA([1 2],3,:) = -1;
            AA([3 4],4,:) = -1;
            AA([1 3],1,:) = dxy1(i,:).';
            AA([2 4],2,:) = dxy2(j,:).';
            B = -[x1(i) x2(j) y1(i) y2(j)].';
            % Loop through possibilities.  If robust is true (any number > 0), trap
            % singularity warning and then use lastwarn to see if that plane of AA is near
            % singular.  Process any such segment pairs to determine if they are colinear
            % (overlap) or merely parallel.  That test consists of checking to see if one of
            % the endpoints of the curve 2 segment lies on the curve 1 segment.  This is
            % done by checking the cross product
            %
            %   (x1(2),y1(2)) - (x1(1),y1(1)) x (x2(2),y2(2)) - (x1(1),y1(1)).
            %
            % If this is close to zero then the segments overlap.
            %
            % If robust is < 0, do the above using the older, slower algorithm.
            %
            % If the robust option is false (0) then we assume no two segment pairs are
            % parallel and just go ahead and do the computation.  If A is ever singular, a
            % warning will appear.  This is faster and obviously you should use it only when
            % you know you will never have overlapping or parallel segment pairs.
            if robust > 0
               	overlap = false(n,1);
               	warning_state = warning('off','MATLAB:singularMatrix');
               	% Use try-catch to guarantee original warning state is restored.
               	try
                  		lastwarn('')
                  		% Attempt to use pagemldivide (new in R2022a) with a fallback to the old
                  		% algorithm if it doesn't exist.
                  		try
                     			T = permute(pagemldivide(AA,permute(B,[1 3 2])),[1 3 2]);
                  		catch
                     			for k = 1:n
                        				[L,U] = lu(AA(:,:,k));
                        				T(:,k) = U\(L\B(:,k));
                     			end
                  		end
                  		% Look more carefully at intersections where any element of each column
                  		% of T is NaN.
                  		suspect_list = find(any(isnan(T),1));
                  		for k = suspect_list
                     			T(:,k) = AA(:,:,k)\B(:,k);
                     			[unused,last_warn] = lastwarn; %#ok<ASGLU>
                     			lastwarn('')
                     			if strcmp(last_warn,'MATLAB:singularMatrix')
                        				% Force in_range(k) to be false.
                        				T(1,k) = NaN;
                        				% Determine if these segments overlap or are just parallel.
                        				overlap(k) = rcond([dxy1(i(k),:);xy2(j(k),:) - xy1(i(k),:)]) < eps;
                     			end
                  		end
                  		warning(warning_state)
               	catch err
                  		warning(warning_state)
                  		rethrow(err)
               	end
               	% Find where t1 and t2 are between 0 and 1 and return the corresponding
               	% x0 and y0 values.
               	in_range = (T(1,:) >= 0 & T(2,:) >= 0 & T(1,:) <= 1 & T(2,:) <= 1).';
               	% For overlapping segment pairs the algorithm will return an intersection
               	% point that is at the center of the overlapping region.
               	if any(overlap)
                  		ia = i(overlap);
                  		ja = j(overlap);
                  		% set x0 and y0 to middle of overlapping region.
                  		T(3,overlap) = (max(min(x1(ia),x1(ia+1)),min(x2(ja),x2(ja+1))) + ...
                     			min(max(x1(ia),x1(ia+1)),max(x2(ja),x2(ja+1)))).'/2;
                  		T(4,overlap) = (max(min(y1(ia),y1(ia+1)),min(y2(ja),y2(ja+1))) + ...
                     			min(max(y1(ia),y1(ia+1)),max(y2(ja),y2(ja+1)))).'/2;
                  		selected = in_range | overlap;
               	else
                  		selected = in_range;
               	end
               	xy0 = T(3:4,selected).';
               	
               	% Remove duplicate intersection points.
               	[xy0,index] = unique(xy0,'rows');
               	x0 = xy0(:,1);
               	y0 = xy0(:,2);
               	
               	% Compute how far along each line segment the intersections are.
               	if nargout > 2
                  		sel_index = find(selected);
                  		sel = sel_index(index);
                  		iout = i(sel) + T(1,sel).';
                  		jout = j(sel) + T(2,sel).';
               	end
            elseif robust < 0 % use the algorithm from version 2.0 of this m-file
               	overlap = false(n,1);
               	warning_state = warning('off','MATLAB:singularMatrix');
               	% Use try-catch to guarantee original warning state is restored.
               	try
                  		lastwarn('')
                  		for k = 1:n
                     			T(:,k) = AA(:,:,k)\B(:,k);
                     			[unused,last_warn] = lastwarn; %#ok<ASGLU>
                     			lastwarn('')
                     			if strcmp(last_warn,'MATLAB:singularMatrix')
                        				% Force in_range(k) to be false.
                        				T(1,k) = NaN;
                        				% Determine if these segments overlap or are just parallel.
                        				overlap(k) = rcond([dxy1(i(k),:);xy2(j(k),:) - xy1(i(k),:)]) < eps;
                     			end
                  		end
                  		warning(warning_state)
               	catch err
                  		warning(warning_state)
                  		rethrow(err)
               	end
               	% Find where t1 and t2 are between 0 and 1 and return the corresponding
               	% x0 and y0 values.
               	in_range = (T(1,:) >= 0 & T(2,:) >= 0 & T(1,:) <= 1 & T(2,:) <= 1).';
               	% For overlapping segment pairs the algorithm will return an intersection
               	% point that is at the center of the overlapping region.
               	if any(overlap)
                  		ia = i(overlap);
                  		ja = j(overlap);
                  		% set x0 and y0 to middle of overlapping region.
                  		T(3,overlap) = (max(min(x1(ia),x1(ia+1)),min(x2(ja),x2(ja+1))) + ...
                     			min(max(x1(ia),x1(ia+1)),max(x2(ja),x2(ja+1)))).'/2;
                  		T(4,overlap) = (max(min(y1(ia),y1(ia+1)),min(y2(ja),y2(ja+1))) + ...
                     			min(max(y1(ia),y1(ia+1)),max(y2(ja),y2(ja+1)))).'/2;
                  		selected = in_range | overlap;
               	else
                  		selected = in_range;
               	end
               	xy0 = T(3:4,selected).';
               	
               	% Remove duplicate intersection points.
               	[xy0,index] = unique(xy0,'rows');
               	x0 = xy0(:,1);
               	y0 = xy0(:,2);
               	
               	% Compute how far along each line segment the intersections are.
               	if nargout > 2
                  		sel_index = find(selected);
                  		sel = sel_index(index);
                  		iout = i(sel) + T(1,sel).';
                  		jout = j(sel) + T(2,sel).';
               	end
            else % non-robust option
               	% Attempt to use pagemldivide (new in R2022a) with a fallback to the old
               	% algorithm if it doesn't exist.
               	try
                  		T = permute(pagemldivide(AA,permute(B,[1 3 2])),[1 3 2]);
               	catch
                  		for k = 1:n
                     			[L,U] = lu(AA(:,:,k));
                     			T(:,k) = U\(L\B(:,k));
                  		end
               	end
               	
               	% Find where t1 and t2 are between 0 and 1 and return the corresponding
               	% x0 and y0 values.
               	in_range = (T(1,:) >= 0 & T(2,:) >= 0 & T(1,:) < 1 & T(2,:) < 1).';
               	x0 = T(3,in_range).';
               	y0 = T(4,in_range).';
               	
               	% Compute how far along each line segment the intersections are.
               	if nargout > 2
                  		iout = i(in_range) + T(1,in_range).';
                  		jout = j(in_range) + T(2,in_range).';
               	end
            end
            function y = mvmin(x)
                % Faster implementation of movmin(x,k) when k = 1.
                y = min(x(1:end-1),x(2:end));
            end
            function y = mvmax(x)
                % Faster implementation of movmax(x,k) when k = 1.
                y = max(x(1:end-1),x(2:end));
                
            end
        end
        
        function Sections = ManifoldComplete(app,solutions,slices)
            
            %For each point on each slice, find the corresponding x and y velocities
            for i = 1:length(slices)
                for j = 1:length(slices{i})
                    %Integrate again using ode113 and set the event function to look at app property for position
                    app.posx = slices{i}(j,1);
                    app.posy = slices{i}(j,2);
                    
                    x0 = solutions{j}.y(:,1);
                    
                    tspan = [0 -60];
                    
                    eventFunc = @(t,x) event_Crossing_Slices(t,x);
                    options   = odeset('Reltol',1e-12,'Abstol',1e-12,'Events',eventFunc);
                    [t,x]     = ode113(app.dynamics,tspan,x0,options);
                    
                    Sections{i}(j,:) = x(end,:);
                end
            end
            
            function [position,isterminal,direction] = event_Crossing_Slices(t,x)
                
                position = x(1) - app.posx;
                
                isterminal = 1;
                
                direction = -1;
                
            end
            
            
        end
        
        function TransposedSections = CoordTranspose(app,Sections,option,index)
            switch option %1 for EM->SE, -1 for SE-> EM
                case 1%Transposes from E-M frame to S-E frame
                    
                    L_AB = app.L_ES/app.L_SE;
                    T_AB = app.T_ES/app.T_SE;
                    
                    for i = 1:length(Sections)
                        theta = 70 + 5*i;
                        R11 = [cosd(theta) -sind(theta); sind(theta) cosd(theta)];
                        R21 = [-sind(theta) -cosd(theta); cosd(theta) -sind(theta)];
                        R22 = R11;
                        R = [R11 zeros(2,2); R21 R22];
                        d_A = [-app.mu_ES, 0, 0, 0]';
                        d_B = [1-app.mu_SE, 0, 0, 0]';
                        for j = 1:length(Sections{i})
                            vec = [Sections{i}(j,1), Sections{i}(j,2), Sections{i}(j,4), Sections{i}(j,5)]'; %Only transpose 2D
                            x_A_in = R*(vec - d_A);
                            
                            x_B_pos = L_AB*x_A_in(1:2);
                            x_B_vel = (L_AB/T_AB)*x_A_in(3:4);
                            
                            x_B_in = [x_B_pos; x_B_vel];
                            
                            x_B_rot = inv(R)*x_B_in + d_B;
                            
                            TransposedSections{i}(j,:) = [x_B_rot(1) x_B_rot(2) 0 x_B_rot(3) x_B_rot(4) 0];
                        end
                    end
                case -1 %This will be for after the IC is selected
                    L_AB = app.L_ES/app.L_SE;
                    T_AB = app.T_ES/app.T_SE;
                    
                    theta = app.PhaseAngleListBox.Value;
                    theta = str2num(theta);
                    
                    R11 = [cosd(theta) -sind(theta); sind(theta) cosd(theta)];
                    R21 = [-sind(theta) -cosd(theta); cosd(theta) -sind(theta)];
                    R22 = R11;
                    R = [R11 zeros(2,2); R21 R22];
                    
                    d_B = [1-app.mu_SE, 0, 0, 0]';
                    d_A = [-app.mu_ES, 0, 0, 0]';
                    
                    vec = [Sections(1), Sections(2), Sections(4), Sections(5)]'; %Only transpose 2D
                    
                    x_B_in = R*(vec - d_B);
                    
                    x_A_pos = x_B_in(1:2)/L_AB;
                    x_A_vel = x_B_in(3:4)/(L_AB/T_AB);
                    
                    x_A_in = [x_A_pos; x_A_vel];
                    
                    x_A_rot = inv(R)*x_A_in + d_A;
                    
                    TransposedSections = [x_A_rot(1) x_A_rot(2) 0 x_A_rot(3) x_A_rot(4) 0];

            end
            
        end
        
        function BCR4BP_Int(app,IC,x_avg,index)

            mu1 = 1-app.mu_SE;
            mu2 = app.mu_SE;
            app.E = -1.500425;
            %Find actual initial condition
            IC = [mu1,IC(1),0,IC(2)];
            %Comput vx using 4.4.4
            r1 = sqrt((IC(1)+mu2)^2 + IC(2)^2);
            r2 = sqrt((IC(1)-mu1)^2 + IC(2)^2);
            U_Bar = (-1/2)*(IC(1)^2 + IC(2)^2) - (mu1/r1) - (mu2/r2) - (1/2)*(mu1*mu2); %Defined by 2.3.9
            vx = -sqrt(-(IC(4))^2 - 2*U_Bar + 2*app.E);
            
            IC = [IC(1),IC(2),0,vx,IC(4),0];
            [EnergyConst] = jacobiValue3D(app,IC,app.mu_SE)/(-2)
            globalVar = 1;
            
            %Now integrate forward in the Earth-Moon dynamics and backwards in the Sun-Earth dynamics
            %Backward integration
            [tS,xS] = integrate(app,globalVar,@(t,x) CR3BP(t,x,app.mu_SE),IC,[0 10],'Poincare_1/4', -1);
            plot(app.ManifoldTubes_SE,xS(:,1),xS(:,2))

            
            %Convert IC back to EM frame
            IC = [x_avg,IC(2),0,vx,IC(5),0];
            IC = CoordTranspose(app,IC,-1,index);
            
            mu1 = 1-app.mu_ES;
            mu2 = app.mu_ES;
            app.E = -1.58;
            %Comput vx using 4.4.4
            r1 = sqrt((IC(1)+mu2)^2 + IC(2)^2);
            r2 = sqrt((IC(1)-mu1)^2 + IC(2)^2);
            U_Bar = (-1/2)*(IC(1)^2 + IC(2)^2) - (mu1/r1) - (mu2/r2) - (1/2)*(mu1*mu2); %Defined by 2.3.9
            vx = sqrt(-(IC(5))^2 - 2*U_Bar + 2*app.E);
            
            IC(4) = vx;
            [EnergyConst] = jacobiValue3D(app,IC,app.mu_ES)/(-2)
            [tE,xE] = integrate(app,globalVar,@(t,x) CR3BP(t,x,app.mu_ES),IC,[0 10],'Poincare_2/3', 1);
            plot(app.ManifoldTubes_ES,xE(:,1),xE(:,2))
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: EarthMoonManifoldTubesButton
        function EarthMoonManifoldTubesButtonPushed(app, event)
            
            %Assign variables for E-M system
            app.mu_ES = 1.215*(10^-2); %Mass parameter
            app.L_ES = 3.850*(10^5); %Distance between centers
            app.V_ES = 1.025; %Orbital Velocity of m1
            app.T_ES = 2.361*(10^6); %Orbital period of m1 and m2
            
            %Assign variables for S-E system
            app.mu_SE = 3.036*(10^-6); %Mass parameter
            app.L_SE = 1.496*(10^8); %Distance between centers
            app.V_SE = 29.784; %Orbital Velocity of m1
            app.T_SE = 3.147*(10^7); %Orbital period of m1 and m2
            
            
            %Find E-M Manifold Tubes
            userInput.E_desired         = app.EnergyEditField.Value;
            userInput.Dimension         = 2;   % 2/3
            userInput.mu                = app.mu_ES; % system Parameter
            userInput.plotDiffCorrec    = 0;   % Is Differential Correction Plotted (0/1)
            userInput.orbit             = 'lyapunov';  % which Orbit, set to Lyapunov for now
            userInput.type              = 'none';  % 'northern'/'southern' for 'halo' else 'none'
            userInput.tolerance         = 1e-6;    % solution tolerance
            userInput.jacobianConst     = -2*app.EnergyEditField.Value; % Required jacobian Constant
            userInput.System      = 'E-M';
            
            userInput.lagrangePt        = 2;
            
            globalVar = getGlobalVariable(app,userInput);
            app.mu = globalVar.userInput.mu;
            app.dynamics = globalVar.functions.systemDynamics;
            i = 1;
            %Compute Stable Manifolds
            globalVar.userInput.manifoldType      = 'stable';
            
            globalVar = plotInvManifold(app,globalVar);
            %Extract Manifold Poincare Crossing States
            app.S_Mani_IC_Pos_First_Poinc{i} = globalVar.FncOutput.invManifoldIC_Pos;
            app.S_Mani_IC_Neg_First_Poinc{i} = globalVar.FncOutput.invManifoldIC_Neg;
            
            %Compute Unstable Manifolds
            globalVar.userInput.manifoldType      = 'unstable';
            
            globalVar = plotInvManifold(app,globalVar);
            %Extract Manifold Poincare Crossing States
            app.U_Mani_IC_Pos_First_Poinc{i} = globalVar.FncOutput.invManifoldIC_Pos;
            app.U_Mani_IC_Neg_First_Poinc{i} = globalVar.FncOutput.invManifoldIC_Neg;
            
            %Gather E-M xL2 Stable Positive as well as S-E xL2 Stable/Unstable Postiive and call function for trajectory design


        end

        % Button pushed function: ViewEnergyLevelsButton
        function ViewEnergyLevelsButtonPushed(app, event)
            step = 1;
            for x = -5:.001:5 %Need to look at both sides to end up with a suitable case 3 energy for JS system something on [-1.519,-1.501] by KoLo
                xvect(step) = x;
                Ux(step) = -0.5*(x^2) - ((1-app.mu)/abs(x+app.mu)) - (app.mu/abs(x-1+app.mu));
                if Ux(step) < -5 %We can assume that if it drops below -5 that is a break in the "lobes" this plot creates
                    Ux(step) = -5;
                end
                step = step +1;
            end
            xtick = 1:length(Ux)/40:length(Ux);
            xticklabel = string(num2cell(-5:0.25:5));
            
            
            %Search through Ux to find the endpoints of the "lobes"
            %For case 3 we need to find the energy for the x3 and x2 lobes.
            num=1;
            critpoint = zeros(6,1);
            
            switch app.SystemDropDown.Value
                case 'Sun-Earth'
                    for x = 2:length(Ux)
                        val_1 = Ux(x-1);
                        val_2 = Ux(x);
                        if val_1 == -5 && val_2 ~=-5 || abs(val_1 - val_2) > .05%Checks for starts
                            critpoint(num) = x;
                            num = num+1;
                        end
                        if val_2 == -5 && val_1 ~=-5 || abs(val_2 - val_1) > .05 %Checks for ends
                            critpoint(num) = x;
                            num = num+1;
                        end
                    end
                case 'Earth-Moon'
                    for x = 2:length(Ux)
                        val_1 = Ux(x-1);
                        val_2 = Ux(x);
                        if val_1 == -5 && val_2 ~=-5%Checks for starts
                            critpoint(num) = x;
                            num = num+1;
                        end
                        if val_2 == -5 && val_1 ~=-5%Checks for ends
                            critpoint(num) = x;
                            num = num+1;
                        end
                    end
            end
            
            %Check for repeats
            critpoint = unique(critpoint);
            
            
            lobe_1 = Ux(critpoint(1):critpoint(2));
            lobe_2 = Ux(critpoint(3):critpoint(4));
            lobe_3 = Ux(critpoint(5):critpoint(6));
            [E1,I1] = max(lobe_3); %Energy value and x associated with L1 point
            [E2,I2] = max(lobe_2); %Energy value and x associated with L2 point
            [E3,I3] = max(lobe_1); %Energy value and x associated with L3 point.
            
            app.EnergyEditField.Limits = [E2,E3];
            
            if app.mu == app.mu_ES
                app.EnergyEditField.Value = -1.58;
            elseif app.mu == app.mu_SE
                app.EnergyEditField.Value = -1.500425;
            end
            
            
            %Plot
            plot(app.EnergyLevels,Ux);
            hold(app.EnergyLevels);
            
            y_E1 = E1*ones(1,length(Ux));
            y_E2 = E2*ones(1,length(Ux));
            y_E3 = E3*ones(1,length(Ux));
            plot(app.EnergyLevels,y_E1,'g-')
            plot(app.EnergyLevels,y_E2,'r-')
            plot(app.EnergyLevels,y_E3,'b-')
            
            xlim(app.EnergyLevels,[critpoint(1) + 1000, critpoint(6) - 1000])
            ylim(app.EnergyLevels,[E1-.1,E3+.1])
            app.EnergyLevels.XTick = xtick;
            app.EnergyLevels.XTickLabel = xticklabel;
            plot(app.EnergyLevels,xvect(critpoint(5)+I1),E1,'g*',xvect(critpoint(3)+I2),E2,'r*',xvect(critpoint(1)+I3),E3,'b*')
        end

        % Value changed function: SystemDropDown
        function SystemDropDownValueChanged(app, event)
            System = app.SystemDropDown.Value;
            app.mu_ES = 1.215*(10^-2); %Mass parameter
            app.mu_SE = 3.036*(10^-6); %Mass parameter
            %All values from Koon et. al.
            switch System
                case 'Earth-Moon'
                    app.mu_ES = 1.215*(10^-2); %Mass parameter
                    app.L_ES = 3.850*(10^5); %Distance between centers
                    app.V_ES = 1.025; %Orbital Velocity of m1
                    app.T_ES = 2.361*(10^6); %Orbital period of m1 and m2
                    app.mu = app.mu_ES;
                case 'Sun-Earth'
                    %Assign variables for S-E system
                    app.mu_SE = 3.036*(10^-6); %Mass parameter
                    app.L_SE = 1.496*(10^8); %Distance between centers
                    app.V_SE = 29.784; %Orbital Velocity of m1
                    app.T_SE = 3.147*(10^7); %Orbital period of m1 and m2
                    app.mu = app.mu_SE;
            end
            
            set(app.ViewEnergyLevelsButton, 'Enable', 'On');
            
            
        end

        % Button pushed function: SunEarthManifoldTubesButton
        function SunEarthManifoldTubesButtonPushed(app, event)
            %Find S-E Manifold Tubes
            userInput.E_desired         = app.EnergyEditField.Value;
            userInput.Dimension         = 2;   % 2/3
            userInput.mu                = app.mu_SE; % system Parameter
            userInput.plotDiffCorrec    = 0;   % Is Differential Correction Plotted (0/1)
            userInput.orbit             = 'lyapunov';  % which Orbit, set to Lyapunov for now
            userInput.type              = 'none';  % 'northern'/'southern' for 'halo' else 'none'
            userInput.tolerance         = 1e-6;    % solution tolerance
            userInput.jacobianConst     = -2*app.EnergyEditField.Value; % Required jacobian Constant
            userInput.System      = 'S-E';
            
            %For each xL point
            userInput.lagrangePt        = 2;
            
            globalVar = getGlobalVariable(app,userInput);
            app.mu = globalVar.userInput.mu;
            
            i = 2;
            %Compute Stable Manifolds
            globalVar.userInput.manifoldType      = 'stable';
            
            globalVar = plotInvManifold(app,globalVar);
            %Extract Manifold Poincare Crossing States
            app.S_Mani_IC_Pos_First_Poinc{i} = globalVar.FncOutput.invManifoldIC_Pos;
            app.S_Mani_IC_Neg_First_Poinc{i} = globalVar.FncOutput.invManifoldIC_Neg;
            
            %Compute Unstable Manifolds
            globalVar.userInput.manifoldType      = 'unstable';
            
            globalVar = plotInvManifold(app,globalVar);
            %Extract Manifold Poincare Crossing States
            app.U_Mani_IC_Pos_First_Poinc{i} = globalVar.FncOutput.invManifoldIC_Pos;
            app.U_Mani_IC_Neg_First_Poinc{i} = globalVar.FncOutput.invManifoldIC_Neg;
        end

        % Button pushed function: FindBallisticCaptureButton
        function FindBallisticCaptureButtonPushed(app, event)
            %Function that finds the intersection of the E-M xL2 SP tube with a line the comes from the barycenter every 5 degrees on 90+-45
            
            Slices = ManifoldSlice(app,app.xL2SPTube); %This finds the location, how do we find the velocities?
            
            %Find all variables of the section
            app.FullSections = ManifoldComplete(app,app.xL2SPTubeSolution,Slices);
            
            %Transpose Sections into S-E coordinate system
            app.TranSections = CoordTranspose(app,app.FullSections,1);
            
            %Take poincare section of S-E xL2 UN tube at P3, then compare that with all of the sections that we took
            S_E_xL2_un_P3 = app.U_Mani_IC_Neg_First_Poinc{2};
            
            p = polyshape(S_E_xL2_un_P3(:,2),S_E_xL2_un_P3(:,5));
            index = 1;
            for i = 1:length(app.TranSections)
                app.CompSect{i} = polyshape(app.TranSections{i}(:,2),app.TranSections{i}(:,5));
                
                app.Sect_x_avg{i} = mean(app.TranSections{i}(:,1));
                Comp = polyshape(app.TranSections{i}(:,2),app.TranSections{i}(:,5));
                
                Int = intersect(p,Comp);
                Sub = subtract(Comp,p);
                if Int.NumRegions ~= 0 && Sub.NumRegions ~= 0%Store for later
                    app.Sect_Int{index} = Comp;
                    istore(index) = 70 + 5*i;
                    index = index + 1;
                end
                
            end
            
            plot(app.PoincSec,p)
            hold(app.PoincSec)
            
            app.PhaseAngleListBox.Items = string(istore);
            
        end

        % Value changed function: PhaseAngleListBox
        function PhaseAngleListBoxValueChanged(app, event)
            value = app.PhaseAngleListBox.Value;
            index = (str2num(value)-70)/5;
            plot(app.PoincSec,app.CompSect{index})
            
        end

        % Button pushed function: SelectTrajectoryButton
        function SelectTrajectoryButtonPushed(app, event)
            
            S_E_xL2_un_P3 = app.U_Mani_IC_Neg_First_Poinc{2};
            
            p1 = polyshape(S_E_xL2_un_P3(:,2),S_E_xL2_un_P3(:,5));
            
            value = app.PhaseAngleListBox.Value;
            index = (str2num(value)-70)/5;
            p2 = app.CompSect{index};
            x_avg = app.Sect_x_avg{index};
            
            polyout = subtract(p2,p1);
            
            figure();
            hold on
            plot(polyout)
            IC = ginput(1);
            
            %Integrate trajectory using BCR4BP dynamics in both directions
            BCR4BP_Int(app,IC,x_avg,index);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EarthMoonManifoldTubesButton = uibutton(app.UIFigure, 'push');
            app.EarthMoonManifoldTubesButton.ButtonPushedFcn = createCallbackFcn(app, @EarthMoonManifoldTubesButtonPushed, true);
            app.EarthMoonManifoldTubesButton.Text = 'Earth-Moon Manifold Tubes';
            app.EnergyEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyLevels = uiaxes(app.UIFigure);
            title(app.EnergyLevels, 'Gravitational Potential for L1,L2,L3')
            xlabel(app.EnergyLevels, 'X (Non Dim)')
            ylabel(app.EnergyLevels, 'Energy (Non Dim)')
            app.EnergyLevels.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ViewEnergyLevelsButton = uibutton(app.UIFigure, 'push');
            app.ViewEnergyLevelsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEnergyLevelsButtonPushed, true);
            app.ViewEnergyLevelsButton.Enable = 'off';
            app.ViewEnergyLevelsButton.Text = 'View Energy Levels';
            app.SystemDropDownLabel = uilabel(app.UIFigure);
            app.SystemDropDownLabel.Text = 'System';
            app.SystemDropDown = uidropdown(app.UIFigure);
            app.SystemDropDown.Items = {'Select System', 'Earth-Moon', 'Sun-Earth'};
            app.SystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemDropDownValueChanged, true);
            app.SystemDropDown.Value = 'Select System';
            app.ManifoldTubes_ES = uiaxes(app.UIFigure);
            title(app.ManifoldTubes_ES, 'Generated Manifold Tubes')
            xlabel(app.ManifoldTubes_ES, 'X (Non Dim)')
            ylabel(app.ManifoldTubes_ES, 'Y (Non Dim)')
            app.ManifoldTubes_SE = uiaxes(app.UIFigure);
            title(app.ManifoldTubes_SE, 'Generated Manifold Tubes')
            xlabel(app.ManifoldTubes_SE, 'X (Non Dim)')
            ylabel(app.ManifoldTubes_SE, 'Y (Non Dim)')
            app.SunEarthManifoldTubesButton = uibutton(app.UIFigure, 'push');
            app.SunEarthManifoldTubesButton.ButtonPushedFcn = createCallbackFcn(app, @SunEarthManifoldTubesButtonPushed, true);
            app.SunEarthManifoldTubesButton.Text = 'Sun-Earth Manifold Tubes';
            app.FindBallisticCaptureButton = uibutton(app.UIFigure, 'push');
            app.FindBallisticCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @FindBallisticCaptureButtonPushed, true);
            app.FindBallisticCaptureButton.Text = 'Find Ballistic Capture';
            app.PoincSec = uiaxes(app.UIFigure);
            title(app.PoincSec, 'Poincare Section')
            xlabel(app.PoincSec, 'Y (Non Dim)')
            ylabel(app.PoincSec, 'Vy (Non Dim)')
            app.PhaseAngleListBoxLabel = uilabel(app.UIFigure);
            app.PhaseAngleListBoxLabel.Text = 'Phase Angle';
            app.PhaseAngleListBox = uilistbox(app.UIFigure);
            app.PhaseAngleListBox.ValueChangedFcn = createCallbackFcn(app, @PhaseAngleListBoxValueChanged, true);
            app.SelectTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrajectoryButtonPushed, true);
            app.SelectTrajectoryButton.Text = 'Select Trajectory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Ballistic_Capture

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end